classdef BRM_Microscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        CurrentDirectory               matlab.ui.control.Label
        Camera_view                    matlab.ui.control.Button
        Exposure_time_2                matlab.ui.control.NumericEditField
        ExpTimemsLabel                 matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ImagePreviewPanel              matlab.ui.container.Panel
        ROI_Magnification              matlab.ui.control.DropDown
        MagnificationDropDown_2Label   matlab.ui.control.Label
        AddROIButton                   matlab.ui.control.Button
        Image_Axis                     matlab.ui.control.UIAxes
        SnapShotPreviewButton          matlab.ui.control.Button
        StageControlPanel              matlab.ui.container.Panel
        AutoFocusButton                matlab.ui.control.Button
        mLabel                         matlab.ui.control.Label
        Step_size                      matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel         matlab.ui.control.Label
        FocusPos_2                     matlab.ui.control.Label
        FocusDownButton                matlab.ui.control.Button
        FocusPos                       matlab.ui.control.Label
        FocusPosLabel                  matlab.ui.control.Label
        FocusUpButton                  matlab.ui.control.Button
        y_coord_text                   matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        text54_4                       matlab.ui.control.Label
        text54_3                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_2                       matlab.ui.control.Label
        text54                         matlab.ui.control.Label
        y_coord_entry                  matlab.ui.control.EditField
        x_coord_entry                  matlab.ui.control.EditField
        SystemStatusPanel              matlab.ui.container.Panel
        CameraStatus                   matlab.ui.control.DropDown
        CameraStatusDropDownLabel      matlab.ui.control.Label
        Acquisition_method             matlab.ui.control.Label
        AcquisitionTypeLabel_2         matlab.ui.control.Label
        CurrentOperationLabel          matlab.ui.control.Label
        System_status                  matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Grid_size_status               matlab.ui.control.Label
        Microscopy_status              matlab.ui.control.Label
        Magnification_status           matlab.ui.control.Label
        GridSizeLabel_2                matlab.ui.control.Label
        MicroscopyMethodLabel          matlab.ui.control.Label
        MagnificationLabel_2           matlab.ui.control.Label
        Progress_Bar                   matlab.ui.control.UIAxes
        filename_ext                   matlab.ui.control.Label
        AcquireImagesButton            matlab.ui.control.Button
        AcquisitionStatusPanel         matlab.ui.container.Panel
        GridStatusLamp                 matlab.ui.control.Lamp
        GridStatusLampLabel            matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        SampleParametersPanel          matlab.ui.container.Panel
        ZStackStepSize                 matlab.ui.control.NumericEditField
        ZStackStepSizeLabel            matlab.ui.control.Label
        ZStackoffset                   matlab.ui.control.NumericEditField
        ZStackOffsetLabel              matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        SamplethicknessOffsetLabel     matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        SampleTypeEditField            matlab.ui.control.EditField
        SampleTypeEditFieldLabel       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        SampleThickness                matlab.ui.control.NumericEditField
        SampleThicknessEditFieldLabel  matlab.ui.control.Label
        GridLayoutPanel                matlab.ui.container.Panel
        ROISelection                   matlab.ui.control.ListBox
        ROISelectionListBoxLabel       matlab.ui.control.Label
        LRLabel_2                      matlab.ui.control.Label
        ULLabel_2                      matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        LR_Location                    matlab.ui.control.Label
        LRLabel                        matlab.ui.control.Label
        UL_Location_lbl                matlab.ui.control.Label
        ULLabel                        matlab.ui.control.Label
        SetLowerRightButton            matlab.ui.control.Button
        SetUpperLeftButton             matlab.ui.control.Button
        GridSelectionDropDown          matlab.ui.control.DropDown
        GridSelectionDropDownLabel     matlab.ui.control.Label
        Current_camera_2               matlab.ui.control.Label
        Camera_status_label_2          matlab.ui.control.Label
        Camera_view_2                  matlab.ui.control.Button
        SelectPositionButton           matlab.ui.control.Button
        UnSelectPositionButton         matlab.ui.control.Button
        Label_6                        matlab.ui.control.Label
        SetZFocusforPositionButton     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        UpButton                       matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        Exposure_time_3                matlab.ui.control.NumericEditField
        ExpTimemsLabel_2               matlab.ui.control.Label
        Step_size_2                    matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel_2       matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        FocusDownButton_2              matlab.ui.control.Button
        FocusPos_3                     matlab.ui.control.Label
        FocusPosLabel_2                matlab.ui.control.Label
        FocusUpButton_2                matlab.ui.control.Button
        Image_Preview_2                matlab.ui.control.Image
        StitchingType                  matlab.ui.control.DropDown
        StitchingTypeDropDownLabel     matlab.ui.control.Label
        Grid_yentry                    matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        Grid_xentry                    matlab.ui.control.NumericEditField
        GridSizeLabel                  matlab.ui.control.Label
        LoadGridButton                 matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        CornersTab                     matlab.ui.container.Tab
        set_LR_coords_button           matlab.ui.control.Button
        set_UL_coords_button           matlab.ui.control.Button
        calculate_grid_button          matlab.ui.control.Button
        SingleCornerTab                matlab.ui.container.Tab
        set_low_right_button           matlab.ui.control.Button
        set_up_left_button             matlab.ui.control.Button
        Image_3                        matlab.ui.control.Image
        set_grid_button                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SaveDirectory                  matlab.ui.control.EditField
        SaveDirectoryDocuments_BU_Bigio_ImagesLabel  matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.Button
        LinearPolarizersPanel          matlab.ui.container.Panel
        Rotate_Detection_2             matlab.ui.control.Button
        IlluminationEditField          matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel     matlab.ui.control.Label
        DetectionEditField             matlab.ui.control.NumericEditField
        DetectionEditFieldLabel        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Rotate_Detection               matlab.ui.control.Button
        CurrentposLabel                matlab.ui.control.Label
        ill_polarizer                  matlab.ui.control.Label
        Det_polarizer                  matlab.ui.control.Label
        AcquisitionPanel               matlab.ui.container.Panel
        AutoFocus                      matlab.ui.control.CheckBox
        Exposure_time                  matlab.ui.control.NumericEditField
        ExposureTimeLabel              matlab.ui.control.Label
        AcquisitionType                matlab.ui.control.DropDown
        AcquisitionTypeLabel           matlab.ui.control.Label
        MicroscopeSetup                matlab.ui.control.DropDown
        MicroscopeSetupLabel           matlab.ui.control.Label
        ImageSettingsPanel             matlab.ui.container.Panel
        ExposureTime2                  matlab.ui.control.NumericEditField
        ExposureTime2EditFieldLabel    matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        ExposureTime1                  matlab.ui.control.NumericEditField
        ExposureTime1Label             matlab.ui.control.Label
        MagnificationDropDown          matlab.ui.control.DropDown
        MagnificationLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        x_coord_motor=55;
        y_coord_motor=37.5;
        det_degree_settings;
        ill_degree_settings;
        det_RCA_LCA_settings;
        ill_RCA_LCA_settings;
        motor_x;
        motor_y;
        motor_ill;
        motor_det;
        save_folder;
        z_step = 1; % 1 micron
        saved_coordinates = cell(1,1);
        saved_coordinates_string = cell(1,1);
        saved_z_coordinates = zeros(1,3);
        saved_z_coordinates_string = cell(1,1);
        
        zstack_display = zeros(1200,1600,41);
        zstack_fm = zeros(41,1);
        i_zstack = 0;
        
        % FOV in microns calculated from calibration slide for each magnification
        mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
        mags = [4 10 16 20 32 40 64 60 96]';
        pixels_per_100_microns = [54 136 210 270 420 542 840 815 1310]';
        FOV_pixels = [1200; 1600]; % [y, x]
    
        mag_index = 4;
        mag = 20;
        obj_string = '20X';
        
        overlap_for_stitching = 0.25; % percent
        
        stitching_perimeter = zeros(1,2);
        stitching_coordinates = 0;
        rectangular_stitching = 0;
        perimeter_stitching = 0;
        rectangular_stitch_folder = '';
        perimeter_stitch_folder = '';
        ULx = 55;
        ULy = 37.5;
        LRx = 0;
        LRy = 250;
        grid_size = 0;
       
        grid_size_x = 2;
        grid_size_y = 2;
        x_grid_mesh;
        y_grid_mesh;
        x_img_mesh;
        y_img_mesh;
        empty_tile = false;
        z_img_mesh = 0;
        FOV_microns_y;
        FOV_microns_x;
        FOV_microns_stitching_y;
        FOV_microns_stitching_x;
        mmc;
        saved_z_positions;
        z_position;
        exposure_time = 400;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%% add ons for new GUI %%%%%%%%%%%%%%%%%%%
        path_location = '\\ad\eng\users\a\l\algray\My Documents\MATLAB\qBRM\'; % main folder location
        Experimental_file_location = ''; % create experiment folder
        acquisiton_type;
        directory_created = 0;
        bigio_folder;
        directory_counter = 2;
        filename_counter = 2 % Description
        
        correction_factor = 15; %Correction from 10X objective to 40x
        exp_times = [2 5 10 15 32 40 64 60 96]'; % Description
        rect_handler = gobjects(2,2); % create graphics array to store graphing handlers
        grid_select_index = [1,1];
        
        Grid_select_points; % number of selected grid points 
        grid_plane; % intialize array to hold points for plane calculation 
        stitched_img_location; % Location of current image that was stiched used for saving 
        ROI_counter = 0; % Number of ROIs
        ROI_handler = gobjects(5,1); % create graphics array to store graphing handlers max is 5 ROIs
        ROI_position_handler; % get position for ROI imaging
        ROI_mag_index = 4; % magnification for ROI imaging from 4X images 
        qBRM_step = 30; % Step size for the illumination/Detection movement during qBRM images
        autofocus_start =1; % Description
    end
    
    methods (Access = private)
       function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);      
        end
        
        function [ill_coord, det_coord] = read_rotat_stage_position(app)
            % read position of the motor
            ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
            det_coord = System.Decimal.ToDouble(app.motor_det.Position); 
            app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
            app.Det_polarizer.Text = sprintf('%.2f',det_coord);
        end
        
        function move_motor(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end
                
        function moveto(app, motor, position)
            try
            workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
            motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
            motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed');
                motor.Home(100000);
            end
        end
        
        function ask_to_insert_16x_lens(app)
            % Ask user to insert 1.6x lens into detection path
            d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
        end
        function ask_user_mmc(app, display_text)
            if(strcmp(app.camera_status.Text, 'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    display_text);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
        end
        

        function plot_grid(app, x_min, x_max, y_min, y_max)
            % Plotting
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x_img_mesh,app.y_img_mesh,'.','Color','red');
            
            plot(app.UIAxes,app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
           
            plot(app.UIAxes,app.x_grid_mesh',app.y_grid_mesh','Color','blue');
            
            ax = gca;
            ax.XLim = [x_min-1.5 x_max+1.5];
            ax.YLim = [y_min-1.5 y_max+1.5];
            ax.YDir = 'reverse';
            ax.XDir = 'reverse';
        end
        
        function load_camera_with_mmc(app)
            try
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
        
            catch ME
                message = 'Error Loading Camera, Restart Camera and Try again';
                uiwait(warndlg(message));
            end 
                
        end
        
        function input_save_filename(app)
            %title = 'Enter the save name for this image set.';
            %user_answer = inputdlg('Enter the save name for this image set:',...
            %    title,[1 length(title)+30]);
            %save_string = user_answer{1,1};
            %generate_file_extension(app);
            
            save_string = ['\' num2str(app.FileName.Value)];
            cd(app.save_folder);
            mkdir(save_string);
            app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.rectangular_stitch_folder);
        end
        
        % Save multi-exposure images for current xy location
        function save_multi_exp_images(app, exp_times, img_index, img, z_focus)
            for i = 1:length(exp_times)
                cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);

                if(img_index < 10)
                    new_filename = sprintf('img_00%d.tif',img_index);
                elseif(img_index > 99)
                    new_filename = sprintf('img_%d.tif',img_index);
                else
                    new_filename = sprintf('img_0%d.tif',img_index);
                end
                % Save images with indexing for ImageJ Stitching plug-in
                imwrite(uint16(img{y,x}(:,:,i)),new_filename);
    
                % Update saved Z-plane information
                save('z_focus.mat','z_focus');
            end
        end
        
        %disconnects all motors
        function disconnect_all_motors(app)
            app.motor_x.StopPolling();
            app.motor_x.Disconnect();
            
            app.motor_y.StopPolling();
            app.motor_y.Disconnect();
            
            app.motor_ill.StopPolling();
            app.motor_ill.Disconnect();
            
            app.motor_det.StopPolling();
            app.motor_det.Disconnect();
        end
        
        %opens window to choose magnification
        function [mag, mag_index] = choose_magnification(app)
            [app.mag_index,~] = listdlg('PromptString','Choose the magnification for stitching acquisition:','SelectionMode','Single',...
                'Name','Magnification Selection','ListSize',[300 200],'ListString',app.mag_list);
            mag = app.mags(app.mag_index,1);
            if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                app.obj_string = app.mag_list{1,app.mag_index};
            else
                app.obj_string = app.mag_list{1,app.mag_index - 1};
            
                % Ask user to insert 1.6x lens into detection path
                d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            mag_index = app.mag_index;
        end   
        
        %find z position, so the sample is in focus
        function [img, current_z] = autofocusing(app, correction_factor, exp_time, objective, z_focus_last)
                % Variable correction_factor is used to correct for the slight displacement
                % in z between images collected with the 10X and 40X objectives
                app.mmc.setConfig('Objective', objective);
                start_pos = 6800;
                if  app.autofocus_start==0  % handles the first autofocus to get us in the general range for sample
                    app.mmc.setPosition(start_pos);
                    pause(1)
                end
                current_z = app.mmc.getPosition;
                done = 0;
                fine_adj = 0;
                n = 1;
                best_pos = [start_pos 0];
                coarse_increment = [75 75 50 50 25 25 15 15];
                fine_increment = [7 7 5 5 3 3 1 1];
                direction = -1; % negative to start first two   
                update_progress_bar(app, 15);
                increment = coarse_increment(app.mag_index); 
                
                %find the proper direction
                for x = 1:3
                    fprintf('Iteration #%d  ',n);
                    app.mmc.setPosition(current_z);
                    pause(0.5);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
                    fm_pos(n,1) = current_z;
                    if fm(n,1) > best_pos(2)
                        best_pos = [fm_pos(n,1) fm(n,1)];
                    end
                    n = n+1;
                    current_z = current_z + direction*increment; % Move objective in the current direction 
                end
                
                if best_pos(1) == start_pos
                    direction = 1;
                    current_z = best_pos(1);
                    app.mmc.setPosition(current_z);
                end
                
                while ~(done)
                    disp(sprintf('Iteration #%d  ',n));
                    if fine_adj
                        increment = fine_increment(app.mag_index);
                        fprintf('(fine) \n');
                    end
                    current_z = current_z + direction*increment; % Move objective in the current direction   
                    app.mmc.setPosition(current_z);
                    pause(0.5);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
                    fm_pos(n,1) = current_z;
                   
                    if fm(n,1) < fm(n-1,1) - 0.3
                        if fine_adj
                            current_z = best_pos(1); %update our currentz to the best position
                            done = 1;
                            figure
                            plot(fm_pos, fm, 'ro')
                        else
                            fine_adj = 1;
                        end
                        
                    else
                        best_pos = [fm(n,1) fm_pos(n,1)];
                    end
                    n = n+1;
                end
               
%                 while ~(done)
%                     fprintf('Iteration #%d  ',n-2);
%                    if fine_adj
%                         increment = fine_increment(app.mag_index);
%                         fprintf('(fine) \n');
%                     end
% 
%                             % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
%                     if(fm(n - 1,1) < fm(n - 2,1))
%                         direction = -direction;
%                     end
%                     
%                     current_z = current_z + direction*increment; % Move objective in the current direction   
%                     app.mmc.setPosition(current_z);
%                     pause(0.5);
%                     img = snap_image(exp_time,app.mmc);
%                     fm(n,1) = fmeasure(img,'BREN');
%                     fm_pos(n,1) = current_z;
%                     
%                     % After n = 3 iteration, the direction the z-stage needs to move has been determined
%                     if(n > 7)
%                         if(fm(n,1) < fm(n-1,1))
%                             update_progress_bar(app, 65);
%                             direction = -direction;
%                             if(fine_adj == 0)
%                                 figure
%                                 plot(fm_pos, fm, 'r.')
%                                 fine_adj = 1;
%                                 
%                             else
%                                 done = 1;
%                             end
%                         end
%                     end
%             
%                 end
%                 
                update_progress_bar(app, 0);
                current_z = round(app.mmc.getPosition) - correction_factor; % Round to nearest integer
                app.FocusPos.Text = num2str(current_z);
                pause(0.3)
        end
        
        function Disable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'off';
            app.SnapShotPreviewButton.Enable = 'off';
            app.y_coord_entry.Enable = 'off';
            app.x_coord_entry.Enable = 'off';
            app.MoveXYmotorButton.Enable = 'off';
            app.FileName.Enable = 'off';
            app.FocusUpButton.Enable = 'off';
            app.FocusDownButton.Enable = 'off';
            app.Camera_view.Enable = 'off';
            app.Step_size.Enable = 'off';
            app.Exposure_time_2.Enable = 'off';
            app.CameraStatus.Enable = 'off';
        end
        
        function Enable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'on';
            app.SnapShotPreviewButton.Enable = 'on';
            app.y_coord_entry.Enable = 'on';
            app.x_coord_entry.Enable = 'on';
            app.MoveXYmotorButton.Enable = 'on';
            app.FileName.Enable = 'on';
            app.FocusUpButton.Enable = 'on';
            app.FocusDownButton.Enable = 'on';
            app.Camera_view.Enable = 'on';
            app.Step_size.Enable = 'on';
            app.Exposure_time_2.Enable = 'on';
            app.CameraStatus.Enable = 'on';
        end
        
        function acquire_preview_image(app)
            img = snap_image(app.Exposure_time.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            disp(fm)
            disp(app.mmc.getPosition)
            disp('-----------------')
            app.System_status.Text = 'Acquiring Image';
            Disable_btn_functionality(app);
            imshow(uint8(img), 'Parent', app.Image_Axis, 'InitialMagnification', 'fit');
            app.Image_Preview_2.ImageSource = repmat(uint8(img), 1, 1, 3);
            app.System_status.Text = 'Standby';
            Enable_btn_functionality(app);
        end
        
        %take entry box input and create save directory (or CD to whatever
        %is already saved
        function create_save_directory(app)
            new_folder_name = app.SaveDirectory.Value;
            cd([app.bigio_folder,'\Images']);
            if ~exist(new_folder_name, 'dir')
                mkdir(new_folder_name);
            end
            app.save_folder = strcat(app.bigio_folder,'\Images\',new_folder_name);
            cd([app.bigio_folder,'\Images\',new_folder_name]);
            app.directory_created = 1;
            
            % try create the file folder
            filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value),...
                'ms_mag', app.MagnificationDropDown.Value, '\'));
            if ~exist(filedir, 'dir')
                mkdir(filedir);
                cd([app.save_folder, '\', filedir]);
                app.FileName.Value = strcat(app.FileName.Value, '_', num2str(app.directory_counter)); % update filename so it doesnt run into errors
            else
                filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag',...
                    app.MagnificationDropDown.Value, '_', num2str(app.directory_counter), '\'));
                mkdir(filedir);
                cd([app.save_folder, '\', filedir]);
                app.filename_counter = app.filename_counter + 1;
            end
            app.save_folder = [app.save_folder, '\', filedir];
        end
        
        function write_image_format(app, image, filename, format)
            imwrite(image, filename, format);
        end
        
        function update_grid(app)
            cla(app.UIAxes,'reset') %reset axis
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler


            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;
            center_position_actualx = zeros(1,x);
            center_position_actualy = zeros(1,y);
            
            %%%%%%%%%%% acutally create grid %%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x_ind = 1:x %start at top left index 
                for y_ind = 1:y
                    %get the top left position for each rectangle depending
                    %on the index (x,y) positions
                    posx = app.ULx - ((x_ind-2)*app.FOV_microns_stitching_x(app.mag_index)/1000); % rectangle corners at each index
                    posy = app.ULy + ((y_ind-2)*app.FOV_microns_stitching_y(app.mag_index)/1000);% rectangle corner at each index
                    center_posx = posx + app.FOV_microns_stitching_x(app.mag_index)/2000; % x retangle center (plotting)
                    center_posy = posy + app.FOV_microns_stitching_y(app.mag_index)/2000; % y retangle center (plotting)
                    
                    %%% calc the actual (x,y) positions for imaging (actual)
                    center_position_actualx(x_ind) = posx - app.FOV_microns_x(app.mag_index)/2000; % The actual position for x
                    center_position_actualy(y_ind) = posy + app.FOV_microns_y(app.mag_index)/2000; % The actual position for y
                    
                    app.rect_handler(y_ind,x_ind) = rectangle(app.UIAxes, 'Position', ...
                    [posx posy app.FOV_microns_stitching_x(app.mag_index)/1000 app.FOV_microns_stitching_y(app.mag_index)/1000], 'FaceColor', 'White');
                    hold(app.UIAxes,'on');
                    app.UIAxes.XDir = 'Reverse';
                    app.UIAxes.YDir = 'Reverse';
                    plot(app.UIAxes, center_posx, center_posy, 'r*');
                end
            end
            
            app.grid_select_index = [1,1];   
            [app.x_img_mesh, app.y_img_mesh] = meshgrid(center_position_actualx, center_position_actualy);
            app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
            try
                acquire_preview_image(app);
            catch ME
                disp('---------------')
            end
        end
        
        function update_grid_2(app)
             cla(app.UIAxes,'reset') %reset axis
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            
            
            center_position_actualx = size(1,x);
            center_position_actualy = zeros(1,y);
            
            %%%%%%%%%%% acutally create grid %%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x_ind = 1:x %start at top left index 
                for y_ind = 1:y
                    %get the top left position for each rectangle depending
                    %on the index (x,y) positions
                    posx = app.ULx - ((x_ind-2)*app.FOV_microns_stitching_x(app.mag_index)/1000); % rectangle corners at each index
                    posy = app.ULy + ((y_ind-2)*app.FOV_microns_stitching_y(app.mag_index)/1000);% rectangle corner at each index
                    center_posx = posx + app.FOV_microns_stitching_x(app.mag_index)/2000; % x retangle center (plotting)
                    center_posy = posy + app.FOV_microns_stitching_y(app.mag_index)/2000; % y retangle center (plotting)
                    
                    %%% calc the actual (x,y) positions for imaging (actual)
                    center_position_actualx(x_ind) = posx - app.FOV_microns_x(app.mag_index)/2000; % The actual position for x
                    center_position_actualy(y_ind) = posy + app.FOV_microns_y(app.mag_index)/2000; % The actual position for y
                    
                    app.rect_handler(y_ind,x_ind) = rectangle(app.UIAxes, 'Position', ...
                    [posx posy app.FOV_microns_stitching_x(app.mag_index)/1000 app.FOV_microns_stitching_y(app.mag_index)/1000], 'FaceColor', 'White');
                    hold(app.UIAxes,'on');
                    app.UIAxes.XDir = 'Reverse';
                    app.UIAxes.YDir = 'Reverse';
                    plot(app.UIAxes, center_posx, center_posy, 'r*');
                end
            end
            
            app.grid_select_index = [1,1];   
            [app.x_img_mesh, app.y_img_mesh] = meshgrid(center_position_actualx, center_position_actualy);
            app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
            
            move_motor(app, center_position_actualx(1),center_position_actualx(1));
            acquire_preview_image(app);
        end
        
        function z_plane = calc_z_plane(app)
            P1 = app.grid_plane(:,:,1); % extract data points to define plane
            P2 = app.grid_plane(:,:,2);
            P3 = app.grid_plane(:,:,3);
            N = cross(P1-P2,P1-P3); % find normal vector
            N = N./norm(N); % normalaze the value
            A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
            x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
            z_plane = (-A.*(app.x_img_mesh-x0)-B.*(app.y_img_mesh-y0)+C.*z0)./C; % find z values with plane equation
            disp(z_plane)
        end
        
        function reset_grid_parameters(app)
            app.Grid_select_points = 1;
            app.grid_plane;
            app.SelectPositionButton.Enable = 'on';
            app.UnSelectPositionButton.Enable = 'off';
        end  
        
        function Fiji_stitching(app, directory, gridx, gridy)
            app.System_status.Text = 'Running Image Stitching';
            update_progress_bar(app, 25); 
                                
            javaaddpath("C:\Program Files\MATLAB\R2020a\java\jar\mij.jar");
            MIJ.start('C:\Program Files\Fiji.app\plugins'); % actually start fiji with the correct plugins 
            MIJ.run("Memory & Threads...", "maximum=24364 parallel=12 run");
            app.System_status.Text = 'Displaying Image';
            update_progress_bar(app, 75); 
            command_str = sprintf('type=[Grid: snake by rows] order=[Right & Down                ] grid_size_x=%d grid_size_y=%d tile_overlap=25 first_file_index_i=1 directory=%s file_names=img_{iii}.tif output_textfile_name=TileConfiguration.txt fusion_method=[Linear Blending] regression_threshold=0.30 max/avg_displacement_threshold=2.50 absolute_displacement_threshold=3.50 compute_overlap computation_parameters=[Save memory (but be slower)] image_output=[Fuse and display]', gridx, gridy, directory);
            try
                MIJ.run('Grid/Collection stitching', command_str) % run stitching command
            catch ME
                message = 'Image is too large for Fiji';
                uiwait(warndlg(message));
            end
           
            file_loc = strcat(app.save_folder, '\exp', app.ExposureTime1.Value, 'ms_stitched.tif');  
            cmd = sprintf('Tiff, path = [%s]',file_loc);
            update_progress_bar(app, 0); 
            app.System_status.Text = 'Writing Image to Disk';
            update_progress_bar(app, 50); 
            %MIJ.run('Saveas...', '') % potential way to saveas image
            try
                image = MIJ.getCurrentImage;
                imshow(uint8(image), 'Parent', app.Image_Axis);
            catch ME
               message = 'Image is too large please save in Fiji';
               uiwait(warndlg(message));
            end


            %imwrite(uint8(image), strcat(app.save_folder, '\exp', app.ExposureTime1.Value, 'ms_stitched.tif'), 'tiff');


            update_progress_bar(app, 100);
            app.stitched_img_location = strcat(app.save_folder, '\exp', app.ExposureTime1.Value, 'ms_stitched.tif');
            
            update_progress_bar(app, 0); 
            app.System_status.Text = 'Standby';
            
            message = 'Click OK to Close Fiji';
            uiwait(warndlg(message));
            %TURN OFF fIJI AND write the image to disk and display on GUI
            MIJ.closeAllWindows 
            MIJ.exit

            
        end
        
        function FIJI_save_img_seq(app, directory, filename)
            javaaddpath("C:\Program Files\MATLAB\R2020a\java\jar\mij.jar");
            MIJ.start('C:\Program Files\Fiji.app\plugins'); % actually start fiji with the correct plugins 
            split_dir = strsplit(directory, '\');
            actual_dir = join(split_dir, '\\');
            actual_dir = actual_dir{1};
            cmd_str = sprintf('path=[%s]', strcat(directory, filename));
            MIJ.run('Open...', cmd_str);
            cd(directory);
            mkdir('IMG_SEQ');
            cd('IMG_SEQ');
            new_dir = [actual_dir 'IMG_SEQ\\'];
            cmd_string = sprintf('format=TIFF save =%s file=[%s]', new_dir, new_dir);
            message = 'Make Sure to save Image sequence into proper folder for post processing';
            uiwait(msgbox(message));
            try
                MIJ.run('Image Sequence... ', cmd_string);
            catch ME
                disp('Image Sequence was Not saved')
            end

            %TURN OFF fIJI AND write the image to disk and display on GUI
            MIJ.closeAllWindows 
            MIJ.exit
        end
        
        function update_progress_bar(app, value)
            rectangle(app.Progress_Bar,"FaceColor",'green','EdgeColor','green', 'Position',[0 0 value/100, 1]);
            app.Progress_Bar.Title.String = sprintf('%.0f%%',round(value));
            drawnow
            if value ==0
                cla(app.Progress_Bar);
                app.Progress_Bar.Title.String = ' ';
            end
        end
        
        function all_events(app, src, event)
            name = event.EventName;
            
            if strcmp(name, 'ROIMoved')
                % cakc the actual grid from this. 
                % Compute actual xy locations for rectangular grid acquisition
                x_max = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)); % the actual position of the ROI in the Img space
                x_min = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(3)/(10*app.pixels_per_100_microns(1));
                x_step = app.FOV_microns_stitching_x(app.ROI_mag_index)/1000;
                x_max = x_max - x_step/2;
                x_img = x_max:-x_step:x_min;
                
                y_max = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1));
                y_min = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(4)/(10*app.pixels_per_100_microns(1)); 
                y_step = app.FOV_microns_stitching_y(app.ROI_mag_index)/1000; % convert FOV in microns to mm
               
                y_max = y_max - y_step/2;
                   
                y_img = y_max:-y_step:y_min;
                
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);


                % need to make a large handler for all of the ROIs and
                % there corresponding grids and info


%                 x_max = event.CurrentPosition(1); %app.ULx;
%                 x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
%                 x_max = x_max + x_step/2; % increase to include the point itself
            
%                 
%             
%                 x_img = x_min:x_step:x_max;
%                 x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
%             
%                 y_img = y_min:y_step:y_max;
%                 y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            end
        end
        
        function all_events2(app, src, event)
            name = event.EventName;
            if strcmp(name, 'ROIMoved')
                % cakc the actual grid from this. 
                % Compute actual xy locations for rectangular grid acquisition
                x_max = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)); % the actual position of the ROI in the Img space
                x_min = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(3)/(10*app.pixels_per_100_microns(1));
                x_step = app.FOV_microns_stitching_x(app.ROI_mag_index)/1000;
                x_max = x_max - x_step/2;
                x_img = x_max:-x_step:x_min;
                
                y_max = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1));
                y_min = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(4)/(10*app.pixels_per_100_microns(1)); 
                y_step = app.FOV_microns_stitching_y(app.ROI_mag_index)/1000; % convert FOV in microns to mm
               
                y_max = y_max - y_step/2;
                   
                y_img = y_max:-y_step:y_min;
                
                [x_img_mesh2,y_img_mesh2] = meshgrid(x_img,y_img);
                
                % need to make a large handler for all of the ROIs and
                % there corresponding grids and info


%                 x_max = event.CurrentPosition(1); %app.ULx;
%                 x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
%                 x_max = x_max + x_step/2; % increase to include the point itself
            
%                 
%             
%                 x_img = x_min:x_step:x_max;
%                 x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
%             
%                 y_img = y_min:y_step:y_max;
%                 y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            end
        end 
        
        function all_events3(app, src, event)
            name = event.EventName;
            if strcmp(name, 'ROIMoved')
                % cakc the actual grid from this. 
                % Compute actual xy locations for rectangular grid acquisition
                x_max = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)); % the actual position of the ROI in the Img space
                x_min = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(3)/(10*app.pixels_per_100_microns(1));
                x_step = app.FOV_microns_stitching_x(app.ROI_mag_index)/1000;
                x_max = x_max - x_step/2;
                x_img = x_max:-x_step:x_min;
                
                y_max = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1));
                y_min = app.ULy - event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(4)/(10*app.pixels_per_100_microns(1)); 
                y_step = app.FOV_microns_stitching_y(app.ROI_mag_index)/1000; % convert FOV in microns to mm
               
                y_max = y_max - y_step/2;
                   
                y_img = y_max:-y_step:y_min;
                
                [x_img_mesh2,y_img_mesh2] = meshgrid(x_img,y_img);


                % need to make a large handler for all of the ROIs and
                % there corresponding grids and info


%                 x_max = event.CurrentPosition(1); %app.ULx;
%                 x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
%                 x_max = x_max + x_step/2; % increase to include the point itself
            
%                 
%             
%                 x_img = x_min:x_step:x_max;
%                 x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
%             
%                 y_img = y_min:y_step:y_max;
%                 y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            end
        end 


        function Check_LR_position(app)
            if (app.ULx < app.LRx) || (app.ULy > app.LRy) 
                message = 'Invalid Location';
                uiwait(warndlg(message));
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_y(app.mag_index)/1000;
            end
            update_UL_LR_lables(app);
        end
        
        function Check_UL_position(app)
            if (app.ULx < app.LRx) || (app.ULy > app.LRy) 
                message = 'Invalid Location';
                uiwait(warndlg(message));
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_y(app.mag_index)/1000;
            end
            update_UL_LR_lables(app);
        end
        
        function update_UL_LR_lables(app)
            app.UL_Location_lbl.Text = sprintf('(%.2f,%.2f)', app.ULx, app.ULy);
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
            else 
                app.LR_Location.Text = 'NA';
            end
        end 
        
        function take_flat_field_image(app, degree_step, num_images, exposure_time)
            disp('Taking flat-field image');
            %save current motor position
            [x_pos, y_pos] = read_motor_position(app);
            % move sample "Home" to image flat-filed
            move_motor(app, 55, 37.5);
            ff_images = take_qBFR_image(app, degree_step, num_images, exposure_time);
            save('ff_images.mat','ff_images');
            % move sample back for imaging
            move_motor(app, x_pos, y_pos);
        end
        
        function take_dark_field_image(app, num_images, exposure_time)
                disp('Taking dark frame');
                app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
                pause(1); % wait until shutter was changed
                dark_frames = zeros([app.FOV_pixels',num_images]);
                for n = 1:num_images
                    dark_frames(:,:,n) = snap_image(exposure_time,app.mmc);
                end
                save('dark_frames.mat','dark_frames');
                clear dark_frames
                app.mmc.setConfig('lightpath','Side Port'); % change light path back to camera
        end
        
        function images = take_qBFR_image(app, degree_step, num_images, exposure_time)
            n_steps = 180/degree_step;
            images = cell(1,1,n_steps);
            for step = 1:n_steps
                app.motor_ill.MoveTo(app.ill_degree_settings(1 + (step - 1)*(degree_step/5)), 60000);
                images{1,1,step} = zeros([app.FOV_pixels',num_images]);
                pause(1);
                for n = 1:num_images
                    images{1,1,step}(:,:,n) = snap_image(exposure_time,app.mmc);
                end
                pause(1);
            end
            app.motor_ill.Home(30000); % Move back to 0 before next acquisition
        end
        

        % take in file location to look at all images in that folder and
        % perform correction and overwrite
        function BaSIC_Correction(app, images_dir, plot_val)
            
            % read in Image set and do correction and overwrite all images
            files =dir([images_dir '*.tif']);
            disp(files)
            for i = 1:length(files)  
                IF(:,:,i) = imread([images_dir files(i).name]); % original image
            end
            update_progress_bar(app, 10);
            % estimate flatfield and darkfield
            % For fluorescence images, darkfield estimation is often necessary (set
            % 'darkfield' to be true)
            [flatfield,darkfield] = BaSiC(IF,'darkfield','true');  
            update_progress_bar(app, 65);
            % plot estimated shading files
            % note here darkfield does not only include the contribution of microscope
            % offset, but also scattering light that entering to the light path, which
            % adds to every image tile
            if plot_val
                figure; subplot(121); imagesc(flatfield);colorbar; title('Estimated flatfield');
                subplot(122); imagesc(darkfield);colorbar;title('Estimated darkfield');
            end 
           
            % image correction
            IF_corr = zeros(size(IF));
            for i = 1:length(files)
                IF_corr(:,:,i) = (double(IF(:,:,i))-darkfield)./flatfield;
            end
            update_progress_bar(app, 85);
            cd(images_dir)
            % save images for stitching (overwrite current files)
            for i = 1:length(files)
                imwrite(uint8(IF_corr(:,:,i)),sprintf('img_%03d.tif', i));
            end
            update_progress_bar(app, 0);
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Disable_btn_functionality(app);
            app.System_status.Text = 'Iniatializing...';
            
            % Choose default command line output for control_new_offline_copy
            current_folder = pwd;
            addpath(current_folder);
            [~, bigio_index_end] = regexp(current_folder,'_Bigio');
            bigio_folder = current_folder(1:bigio_index_end);
            app.bigio_folder = bigio_folder;
            
            % Include path to MATLAB folder
            matlab_folder = [bigio_folder,'\MATLAB'];
            addpath(matlab_folder);
            
            %% add path for the BaSIC correction scripts
            addpath("C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\MATLAB\qBRM_BMO\APP_designer\");
            addpath("C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\MATLAB\qBRM_BMO\APP_designer\dcttool\");
            
            % Include path to functions
            addpath([matlab_folder,'\functions']);
            % Load stages and settings
            for nnn = 1:1
                calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
                addpath(calibration_path{1,1});
                ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
                det_degree_settings = load('det_5_degree_settings.mat'); %detection
                calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
                addpath(calibration_path{2,1});
                ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
                det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
                addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
                % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local
            
                field_names = fieldnames(det_degree_settings);
                app.det_degree_settings = det_degree_settings.(field_names{1,1});
                field_names = fieldnames(ill_degree_settings);
                app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
                field_names = fieldnames(det_RCA_LCA_settings);
                app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
                field_names = fieldnames(ill_RCA_LCA_settings);
                app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
                clearvars field_names

            end

            %app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
            %app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1)); 
            
            % Create time-stamped save folder each time an imaging session is started
            t = clock;
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
                t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            cd([bigio_folder,'\Images']);
            %mkdir(new_folder_name);
            %app.save_folder = [bigio_folder,'\Images\',new_folder_name];
            %app.save_dir_user_entry.Value = app.save_folder;
            %cd(app.save_folder);
            
            max_slider_val = 36;
            min_slider_val = 1;
            slider_steps1 = 1/(max_slider_val - min_slider_val);
            slider_steps2 = 3/(max_slider_val - min_slider_val);
            
            %app.mmc.setConfig('Objective', app.obj_string); % set magnification
            %!C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            
            app.FOV_microns_y = 100*app.FOV_pixels(1,1)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.FOV_pixels(2,1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
            
            % set grid size *(Default = [2,2])
            app.grid_size = [app.Grid_yentry.Value, app.Grid_xentry.Value];
            app.Grid_select_points = 1;
            %%%%%%%%%%% after Old GUI %%%%%%%%%%%%%%%%%%%%%
            current_folder = pwd;
            current_folder = strcat(current_folder, "\");
            %addpath(current_folder);
            %[~, bigio_index_end] = regexp(current_folder,'_Bigio');
            %bigio_folder = current_folder(1:bigio_index_end);
            %cd([bigio_folder,'\Images']);
            
            t = clock;
            
            % can replace with an experimental file
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            app.FileName.Value = new_folder_name; % update default file name
            app.SaveDirectory.Value = new_folder_name;
            
            
            % load grid into window axis
            %plot_grid(app, x_min, x_max, y_min, y_max)
          
            
            %%%% set all default values
            app.MagnificationDropDown.Value = app.mag_list{1,app.mag_index};
            app.Microscopy_status.Text = app.MicroscopeSetup.Value;
            app.Magnification_status.Text = app.MagnificationDropDown.Value;
            grid_1 = strcat(string(app.grid_size_x),'x');
            grid_string = strcat(grid_1,string(app.grid_size_y));
            app.Grid_size_status.Text = grid_string;
            
            %% create grid onto axis 
            app.System_status.Text = 'Creating Grid';
            update_grid(app);
            
            % load camera and motor last 
            load_camera_with_mmc(app);
            update_progress_bar(app, 10);
            % set up proper objective
            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            app.mmc.setPosition(7000); % default to this position close to focal plane of most tissues
            pause(1)
            % load in the current focus position
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            app.System_status.Text = 'Initializing Stage...';
            
            % initialize Motor Last
            SN_ill = '55001048';
            SN_det = '55001045';
            SN_xy_controller = '73109054';
            
            %create motor objects and home devices
            [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
            update_progress_bar(app, 25);
            app.System_status.Text = 'Initializing Illumination Stage';
            app.motor_ill = rotat_stage_init(SN_ill);
            update_progress_bar(app, 50);
            app.System_status.Text = 'Initializing Detection Stage';
            app.motor_det = rotat_stage_init(SN_det);
            update_progress_bar(app, 75);
            % Start in LCP-RCA configuration
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
            update_progress_bar(app, 100);
            Enable_btn_functionality(app);
            app.System_status.Text = 'Standby';
            update_progress_bar(app, 0);
            disp('Initialization finished');
        end

        % Button pushed function: AcquireImagesButton
        function AcquireImagesButtonPushed(app, event)
            type = app.AcquisitionType.Value;
            microscopy_type = app.MicroscopeSetup.Value;
            
            switch microscopy_type
                case 'CCP-BRM'
                    create_save_directory(app);
                    switch type
                        case 'Single Image'
                            update_progress_bar(app, 10); 
                            
                            app.System_status.Text = 'Acquiring Image';
                            Disable_btn_functionality(app);
                            update_progress_bar(app, 25); 
                            img = snap_image(app.Exposure_time.Value,app.mmc);
                            
                            app.Image_Preview_2.ImageSource = repmat(img, 1, 1, 3);
                            update_progress_bar(app, 67); 
                            %% create save directory
                            create_save_directory(app)
                            
                            % actually save the image as both a tif and a PNG
                            filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                            update_progress_bar(app, 80); 
                            
                            % write to file
                            imwrite(uint8(img), filename, 'tiff');
                            imshow(uint8(img), 'Parent', app.Image_Axis);
                            update_progress_bar(app, 100); 
                            Enable_btn_functionality(app);
                            
                            app.System_status.Text = 'Standby';
                            update_progress_bar(app, 0); 
                            %potentially write as a png instead of tiff
                            %write_image_format(app, img, filename, 'png');
                            
                            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%                        Z-Stack Imaging                               %%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Z-Stack'
                            disp('Case 2')
                            app.System_status.Text = 'Creating Save Directory';
                            Disable_btn_functionality(app);

                            % check if filename already exists
                            filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                            if isfile(filename)
                                name = strcat(app.FileName.Value, '(', num2str(app.filename_counter), ')');
                                app.FileName.Value = name;
                                filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', app.MagnificationDropDown.Value, '.tiff');
                                app.filename_counter = app.filename_counter + 1;
                            end
                            cd(app.save_folder)
                            % get current position 
                            current_z = app.mmc.getPosition;
                            %% autofocus if it is checked
                            if app.AutoFocus.Value
                                app.System_status.Text = 'Auto-Focusing';
                                [img, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, '10X', 'nothing');
                            end
                            
                            app.System_status.Text = 'Acquring Z-Stack';
                            % go to proper focusing with the correct objective and
                            % give preview
                            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                            img = snap_image(app.Exposure_time.Value,app.mmc);
                            
                            app.Image_Preview_2.ImageSource = repmat(img, 1, 1, 3);
        
                            thickness = app.SampleThickness.Value/2;
                            offset = app.ZStackoffset.Value;
                            step = app.ZStackStepSize.Value;
                            
                            % create Z positions for Z_stack 
                            z_positions = (current_z-thickness-offset):(step):...
                            (current_z + thickness + offset);
                        
                            num_z_steps = length(z_positions);
                            zstack = zeros([app.FOV_pixels',num_z_steps+1]); % space for Z stack images
                            
                            for i = 1:length(z_positions)
                                update_progress_bar(app, round(100*(i/length(z_positions))));
                                app.mmc.setPosition(z_positions(i));
                                zstack(:,:,i) = double(snap_image(app.Exposure_time.Value,app.mmc));
                            end
                            
                            fig3 = figure('Position',[1000 200 800 600],'Name','Z-Stack');
                            imshow3D(uint8(zstack));
                            
                            % write z-stack to file location 
                            for i = 1:size(zstack,3)
                                imwrite(uint8(zstack(:,:,i)) ,filename,'tif','WriteMode','append');
                            end
                            save('Z-Stack_positions.mat', 'z_positions')
                            %create an image sequence for Photoshop
                            %autofocusing
                            FIJI_save_img_seq(app, app.save_folder, filename);
                            
                            update_progress_bar(app, 0);
                            % enable all buttons back after acqusition
                            Enable_btn_functionality(app);
                            app.mmc.setPosition(current_z); % reset the focus back to the center plane
                            app.System_status.Text = 'Standby';
                            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%                        Sitching Imaging                              %%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Stitching'
                            disp('Case 3')
                            
                            
                            if app.Grid_select_points > 3
                                Disable_btn_functionality(app);
                                app.System_status.Text = 'Creating Directories';
                                % create exposure time files
                                cd(app.save_folder);
                                exp_folder_1 = sprintf('exp%dms',app.ExposureTime1.Value);
                                exp_folder_2 = sprintf('exp%dms',app.ExposureTime2.Value);
                                mkdir(exp_folder_1);
                                mkdir(exp_folder_2);
                                
                                z_size = 1; % the number of planes to take
                                
                                % if it is multiplane then create the z values
                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                    [num_images_row,num_images_col] = size(app.x_img_mesh);
                                    z_mesh = zeros(num_images_row,num_images_col,(app.SampleThickness.Value + app.ZStackoffset.Value));
                                    z_mesh(:,:,1) = calc_z_plane(app) - ((app.SampleThickness.Value/2)+app.ZStackoffset.Value)*ones(num_images_row,num_images_col); % get bottom plane
                                   
                                    for z = 1:(app.SampleThickness.Value)+app.ZStackoffset.Value
                                        z_mesh(:,:, z+1) =  z_mesh(:, :, z) + ones(num_images_row,num_images_col);
                                    end
                                end
                                
                                y = 0;
                                index = 0;
                                app.System_status.Text = 'Stitching Acqusition';
                                for y_idx = 1:size(app.x_img_mesh,1)
                                    y = y + 1;
                                    for x_idx = 1:size(app.x_img_mesh,2)
                                        if(mod(y,2) == 1)
                                            % For odd number rows, moving left to right
                                            x = x_idx;
                                        else
                                            % For even number rows, moving right to left
                                            x = size(app.x_img_mesh,2) - x_idx + 1;
                                        end
                                        %move to the preset point
                                        move_motor(app,app.x_img_mesh(y,x), app.y_img_mesh(y,x));
                                        pause(0.1);
                        
                                        % Move objective to z_focus value for the current tile
                                       
                                        
                                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                                            img = cell(2,1);
                                            img{1,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                            img{2,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                            for z = 1:size(z_mesh,3)
                                                app.mmc.setPosition(z_mesh(y,x,z));
                                                pause(0.2);
                                                img{1,1}(:,:,z) = snap_image(app.ExposureTime1.Value,app.mmc);
                                                img{2,1}(:,:,z) = snap_image(app.ExposureTime2.Value,app.mmc);
                                            end
                                        else
                                            
                                            app.mmc.setPosition(app.z_img_mesh(y,x));
                                            pause(0.15);
                                            img_exp_1 = snap_image(app.ExposureTime1.Value, app.mmc);
                                            img_exp_2 = snap_image(app.ExposureTime2.Value, app.mmc);
                                        end 
                                        index = index + 1;        % round number of the current index/(total # of images)
                                        update_progress_bar(app, round(100*(index/(size(app.x_img_mesh,1)*size(app.x_img_mesh,2))))); 
                                        
                                        
                                        % Save multi-exposure images for current xy location
                                        %cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                                        new_filename = sprintf('img_%03d.tif',index);

                                        % save as either multiplane or single
                                        % plane
                                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                                            cd([app.save_folder, '\', exp_folder_1]);
                                            for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                                imwrite(uint8(img{1,1}(:,:,z)),[new_filename],'tif','WriteMode','append');
                                            end
                                            cd([app.save_folder, '\', exp_folder_2]);
                                            for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                                imwrite(uint8(img{2,1}(:,:,z)),[new_filename],'tif','WriteMode','append');
                                            end
                                        else 
                                            % Save images with indexing for ImageJ Stitching plug-in
                                            cd([app.save_folder, '\', exp_folder_1]);
                                            imwrite(uint8(img_exp_1),new_filename);
                                            cd([app.save_folder, '\', exp_folder_2]);
                                            imwrite(uint8(img_exp_2),new_filename);
                                        end
                                    end
                                end
                                
                                    dir = [app.save_folder, '\', exp_folder_1];
                                    Fiji_stitching(app, dir, size(app.x_img_mesh,2), size(app.x_img_mesh,1))
                                    update_progress_bar(app, 0); 
                                    x_img_mesh = app.x_img_mesh;
                                    y_img_mesh = app.y_img_mesh;
                                    x_grid_mesh = app.x_grid_mesh;
                                    y_grid_mesh = app.y_grid_mesh;
                                    mag = app.mag;
                                    
                                    save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag'); 
                                    
                                    
                                    %% perform background correction
                                    app.System_status.Text = 'Performing Background Correction';
                                    file_location1 = [app.save_folder, exp_folder_1, '\'];
                                    file_location2 = [app.save_folder, exp_folder_2, '\'];
                                    BaSIC_Correction(app, file_location1, 0);
                                    BaSIC_Correction(app, file_location2, 0);
                                    % actually do the stitching in FIJI
                                    %  if the grid
                                    % size is small enough
                                    Fiji_stitching(app, dir, size(app.x_img_mesh,2), size(app.x_img_mesh,1))
                                    Enable_btn_functionality(app);
                                    app.System_status.Text = 'Standby';
                                else 
                                    message = 'Grid Has not been initialized';
                                    uiwait(warndlg(message));
                            end
                                
                            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%                        Widefield Acqusition                          %%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Widefield (4X)'
                            disp('Case 4')
                            if app.Grid_select_points > 3
                                app.System_status.Text = 'Creating Directories';
                                % create exposure time files
                                cd(app.save_folder);
                                exp_folder_1 = sprintf('exp%dms',app.ExposureTime1.Value);
                                exp_folder_2 = sprintf('exp%dms',app.ExposureTime2.Value);
                                mkdir(exp_folder_1);
                                mkdir(exp_folder_2);
                                
                                z_size = 1; % the number of planes to take
                                
                                y = 0;
                                index = 0;
                                app.System_status.Text = 'Stitching Acqusition';
                                for y_idx = 1:size(app.x_img_mesh,1)
                                    y = y + 1;
                                    for x_idx = 1:size(app.x_img_mesh,2)
                                        if(mod(y,2) == 1)
                                            % For odd number rows, moving left to right
                                            x = x_idx;
                                        else
                                            % For even number rows, moving right to left
                                            x = size(app.x_img_mesh,2) - x_idx + 1;
                                        end
                                        %move to the preset point
                                        move_motor(app,app.x_img_mesh(y,x), app.y_img_mesh(y,x));
                                        pause(0.1);
                        
                                        % Move objective to z_focus value for the current tile
                                        app.mmc.setPosition(app.z_img_mesh(y,x));
                                        pause(0.25);
                                        
                                        % since widefield has larger structures
                                        % downsample to 8 bit for the images to
                                        % allow for larger stitching images
                                        img_exp_1 = snap_image(app.ExposureTime1.Value, app.mmc);
                                        img_exp_2 = snap_image(app.ExposureTime2.Value, app.mmc);
                                       
                                        index = index + 1;

                                        new_filename = sprintf('img_%03d.tif',index);

                                        % save as either multiplane or single
                                        % plane
                                       
                                        % Save images with indexing for ImageJ Stitching plug-in
                                        cd([app.save_folder, '\', exp_folder_1]);
                                        imwrite(uint8(img_exp_1),new_filename);
                                        cd([app.save_folder, '\', exp_folder_2]);
                                        imwrite(uint8(img_exp_2),new_filename);
                                    end
                                end
                                
                                    dir = [app.save_folder, '\', exp_folder_1];
                                    
                                    % actually do the stitching in FIJI
                                    Fiji_stitching(app, dir, size(app.x_img_mesh,2), size(app.x_img_mesh,1))
                                    
                                    app.System_status.Text = 'Standby';
                                    app.AddROIButton.Enable = 'on';
                                    app.ROI_Magnification.Enable = 'on';
                            else 
                                message = 'Grid Has not been initialized';
                                uiwait(warndlg(message));
                            end
                    end
                    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        qBRM                                          %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'qBRM'
                     %% create save directory
                    create_save_directory(app)
                    

                    switch type      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Single Image                                  %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Image'
                            
                            update_progress_bar(app, 10); 
                            app.System_status.Text = 'Acquiring qBRM Image';
                            Disable_btn_functionality(app);

                            for step = 1:ceil(180/app.qBRM_step)
                                if( step == 1)
                                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                                end
                                update_progress_bar(app, 100*(step/6)); 
                                app.motor_ill.MoveTo(app.ill_degree_settings(1 + (step - 1)*(app.qBRM_step/5)), 60000);
                                img = uint8(snap_image(app.Exposure_time.Value,app.mmc));
                                file = sprintf('img_00%d.tif', step);
                                %imwrite(uint8(img), file, 'tiff');
                                save(sprintf('img_00%d.mat', step),'img','-v7.3');
                            end
                            
                            app.System_status.Text = 'Reseting Illumination Polarizer';
                            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
                            pause(3) % wait for stage to get back
                            [~, ~] = read_rotat_stage_position(app);
                            
                            Enable_btn_functionality(app);
                            
                            app.System_status.Text = 'Standby';
                            update_progress_bar(app, 0); 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                       qBRM Z-Stack                                   %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Z-Stack'
                            % get current position 
                            current_z = app.mmc.getPosition;
                            %% autofocus if it is checked
                            if app.AutoFocus.Value
                                app.System_status.Text = 'Auto-Focusing';
                                [~, ~] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, '10X', 'nothing');
                                [~, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
                            end
                            
                            app.System_status.Text = 'Acquring Z-Stack';
                            % go to proper focusing with the correct objective and
                            % give preview
                            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                            
                            thickness = app.SampleThickness.Value/2;
                            offset = app.ZStackoffset.Value;
                            step = app.ZStackStepSize.Value;
                            
                            % create Z positions for Z_stack 
                            z_positions = (current_z-thickness-offset):(step):...
                            (current_z + thickness + offset);
                            num_z_steps = length(z_positions);
                            for index = 1:num_z_steps
                                mkdir(sprintf('qBRM_ZStack_img_%03d', index)); 
                            end
                            
                            zstack = zeros([app.FOV_pixels',num_z_steps+1]); % space for Z stack images
                            for step = 1:6 % step 30 degrees from current to 180
                                app.System_status.Text = sprintf('Acquiring Angle #%d', step);
                                drawnow
                                for i = 1:length(z_positions)
                                    new_filename = sprintf('img_%d', step); 
                                    update_progress_bar(app, round(100*(i/length(z_positions)))); 
                                    app.mmc.setPosition(z_positions(i)); 
                                    img = double(snap_image(app.Exposure_time.Value,app.mmc));
                                    % only save the first polarizer
                                    % position as the ZStack
                                    if (step ==1)
                                        zstack(:,:,i) = img;
                                    end
                                    
                                    cd([app.save_folder '/' sprintf('qBRM_Zstack_img_%03d',i)]); % go to folder 
                                    save([new_filename,'.mat'],'img','-v7.3');
                                end
                                
                                app.motor_ill.MoveTo(app.ill_degree_settings(1 + (step - 1)*(app.qBRM_step/5)), 60000); % move the linear polarizer after each image
                                pause(1)
                            end 
                            fig3 = figure('Position',[1000 200 800 600],'Name','Z-Stack');
                            imshow3D(uint8(zstack));
                            app.System_status.Text = 'Homing Illumination Polarizer';
                            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
                            pause(3)
                            [~, ~] = read_rotat_stage_position(app);
                            update_progress_bar(app, 0);
                            % enable all buttons back after acqusition
                            Enable_btn_functionality(app);
                            app.mmc.setPosition(current_z); % reset the focus back to the center plane
                            save('Z-Stack_positions.mat', 'z_positions')
                            app.System_status.Text = 'Standby';

                        case 'Stitching'
                            disp('Stitching')
                            if app.Grid_select_points > 3
                                % Make folders for the different illumination polarizer angles
                                for q = 1:num_qBRM_images
                                    mkdir(sprintf('qBRM_img%d',q));
                                end
                                
                                % Stitching Acquisition (for ImageJ Stitching plugin):
                                % Grid: row-by-row
                                % Order: snake (left-to-right row 1)
                                % if it is multiplane then create the z values
                                qBRM_img = cell([size(app.x_img_mesh),length(num_qBRM_images)]); % Changing img to accomodate 10-image bursts for ff correction
                                for step = 1:num_qBRM_images
                                    % Save qBRM images for current xy location
                                    if( step == 1)
                                        pause(3); % Extra pause to allow the motorized stage to get to the first setting
                                    end
                                    pause(1);
                                    y = 0;
                                    img_index = 0;
                                    %take stitching image at current coordinates
                                    for y_loop = 1:size(app.x_img_mesh,1)
                                        y = y + 1;
                                        for x_loop = 1:size(app.x_img_mesh,2)
                                            if(mod(y,2) == 1)
                                                % For odd number rows, moving left to right
                                                x = x_loop;
                                            else
                                                % For even number rows, moving right to left
                                                x = size(app.x_img_mesh,2) - x_loop + 1;
                                            end
                                            y_current = app.y_img_mesh(y,x);
                                            x_current = app.x_img_mesh(y,x);
                                            move_motor(app, x_current, y_current);
                                            % Move objective z position
                                            app.mmc.setPosition(z_focus(y,x));
                                            pause(0.2);
                           
                                            img = snap_image(app.Exposure_time_2.Value,app.mmc);
                                            
                                            img_index = img_index + 1;
                                            cd([app.save_folder '/' sprintf('qBRM_img_%03d',step)]); % go to folder 
                                            new_filename = sprintf('img_%03d',img_index);
                                            save([new_filename,'.mat'],'img','-v7.3');
                                        end
                                    end
                                    app.motor_ill.MoveTo(app.ill_degree_settings(1 + (step - 1)*(app.qBRM_step/5)), 60000); % move the linear polarizer after each image
                                    pause(0.5)
                                end
                                
                                % Acquire flat-field and dark frame images for flat-field correction
                                % Take flat-field correction image
                                take_flat_field_image(app, degree_step, num_images, exp_time);
                                % Disable computer control of microscope to allow user to change light path to eyepieces for dark frames
                                take_dark_field_image(app, num_images, exp_time);
                                app.mmc.reset();
                                
                                % home the Illumination Polarizer
                                app.System_status.Text = 'Homing Illumination Polarizer';
                                app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
                                pause(3)
                                [~, ~] = read_rotat_stage_position(app);
                                
                            else
                                message = 'Grid Has not been initialized';
                                uiwait(warndlg(message));
                            end 
                                case 'Widefield'
                            disp('Widefield')  
                    end   
            end 
%             currentFolder = pwd;
%             app.CurrentDirectory.Text = strcat('Current Directory: ',  currentFolder);
        end

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            value = app.MagnificationDropDown.Value;
            app.Magnification_status.Text = value;
            app.mag_index = find(app.mags == str2num(erase(convertCharsToStrings(value),"X"))); %Find index from the Mag character
            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            app.Exposure_time.Value = app.exp_times(app.mag_index);
            app.Exposure_time_2.Value = app.exp_times(app.mag_index);
            
            update_grid(app);
            % have a little check in case the buttons are accidently
            % disabled 
            Enable_btn_functionality(app);
            
        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            move_motor(app,app.x_coord_motor, app.y_coord_motor);
        end

        % Button pushed function: SnapShotPreviewButton
        function SnapShotPreviewButtonPushed(app, event)
            acquire_preview_image(app);
           
        end

        % Button pushed function: Camera_view, Camera_view_2
        function Camera_viewPushed(app, event)
%             if (strcmp(app.Current_camera.Text, 'MATLAB'))
%                 app.System_status.Text = 'Moving to Micro-Manager';
%                 app.FocusDownButton.Enable = 'off';
%                 app.FocusUpButton.Enable = 'off';
%                 app.SnapShotPreviewButton.Enable = 'off';
%                 app.AcquireImagesButton.Enable = 'off';
%                 % Set camera to Eye peice on microscope
%                 %app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
%                 app.mmc.reset();
%                 !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
%                 app.Current_camera.Text = 'Micro-Manager Eye-Piece';
%                 app.Current_camera_2.Text = 'Micro-Manager Eye-Piece';
%                 app.System_status.Text = 'Standby';
%             else 
%                 app.System_status.Text = 'Loading Camera to MATLAB';
%                 load_camera_with_mmc(app);
%                 app.mmc.setConfig('lightpath','Side Port'); % change light path to look at eyepiece (dark field)
%                 formatSpec = '%.1f';
%                 app.z_position = app.mmc.getPosition;
%                 app.FocusPos.Text = num2str(app.z_position, formatSpec);
%                 app.Current_camera.Text = 'MATLAB';
%                 app.Current_camera_2.Text = 'MATLAB';
%                 app.System_status.Text = 'Standby';
%                 app.FocusDownButton.Enable = 'on';
%                 app.FocusUpButton.Enable = 'on';
%                 app.SnapShotPreviewButton.Enable = 'on';
%                 app.AcquireImagesButton.Enable = 'on';  
%             end
        end

        % Button pushed function: FocusUpButton, FocusUpButton_2
        function FocusUpButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position + app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            % acquire Image and update
            acquire_preview_image(app);
        end

        % Button pushed function: FocusDownButton, FocusDownButton_2
        function FocusDownButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position - app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            %acquire image for preview
            acquire_preview_image(app);
            
        end

        % Callback function
        function SetZFocusButtonPushed(app, event)
             if isempty(app.x_img_mesh)
                % error
                d = dialog('Position',[300 300 400 100],'Name','Grid Information is Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Imaging coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            else
                % Plotting
                fig1 = figure('Position',[300 50 1200 900]);
                hold on;
                plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
                plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
                plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
                x_min = min(app.x_img_mesh(1,:));
                x_max = max(app.x_img_mesh(1,:));
                y_min = min(app.y_img_mesh(:,1));
                y_max = max(app.y_img_mesh(:,1));
                xlim([x_min-1.5 x_max+1.5]);
                ylim([y_min-1.5 y_max+1.5]);
                ax = gca;
                ax.YDir = 'reverse';
                ax.XDir = 'reverse';
                xlabel('X coordinates (mm)');
                ylabel('Y coordinates (mm)');
                title('Select Upper Left sample tile for focusing.'); % Start with upper left
                
                % Ask user to switch to correct objective and view with Micro-Manager
                d = dialog('Position',[300 300 400 100],'Name','Use Micro-Manager for Live View');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    ['Move ',app.obj_string,' into place and view sample with Micro-Manager.']);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
                
                % Manually select tiles, go to selected coordinates, and ask user to designate if empty
                z_focus_corners = zeros(3,1);
                corner_indices = zeros(3,2);
                fprintf('First, select Upper Left sample tile for focusing.\n');
                count = 0;
                while count < 3
                    %read mouse position
                    [x,y] = ginput(1);
                    %end function by closing interface
                    if(isempty(x) == true && isempty(y) == true)
                        break;
                    else
                        % calculating based on where you click what lines of rectangle to
                        % highlight
                        [~,x_ind] = min(abs(x*ones(1,length(app.x_img_mesh(1,:))) - app.x_img_mesh(1,:)));
                        [~,y_ind] = min(abs(y*ones(length(app.y_img_mesh(:,1)),1) - app.y_img_mesh(:,1)));
                        closed_rect = [app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind)];
                        tile_highlight1 = plot(closed_rect(:,1),closed_rect(:,2),'Color','red','LineWidth',5);
                
                        % Go to selected XY coordinates
                        y_coord = app.y_img_mesh(y_ind,1);
                        x_coord = app.x_img_mesh(1,x_ind);
                        move_motor(app, x_coord, y_coord);
                
                        % Ask user to confirm that they would like to use this tile for determining objective focus for the current corner
                        use_tile_ans = questdlg('Use this tile for determining Z-focus position in this corner of the sample?',...
                            'Choose corner tile for determining Z-focus','Yes','No - choose a new tile','Yes');
                        if(strcmp(use_tile_ans,'Yes') == 1)
                            % Tell user to close Micro-Manager to get objective Z-position
                            d = dialog('Position',[300 300 400 100],'Name','Bring sample into focus and close Micro-Manager to get objective Z-position');
                            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                                'Close Micro-Manager before clicking "Continue".');
                            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                                'Callback','delete(gcf)');
                            %waits until you click continue
                            uiwait(d);
                
                            % Load microscope hardware through Micro-Manager core and get objective position
                            import mmcorej.*;
                            app.mmc = CMMCore;
                            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
                            app.camera_status.Text = 'MATLAB';
                
                            count = count + 1;
                            z_focus_corners(count,1) = app.mmc.getPosition;
                            corner_indices(count,:) = [y_ind,x_ind];
                            app.mmc.reset();
                            if(count < 3)
                                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                                app.camera_status.Text = 'Micro-Manager';
                            end
                
                            % Display recorded z-focus and indicate next corner to focus
                            if(count == 1)
                                P1 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Upper Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Upper Right sample tile for focusing.'); % UL --> UR
                            elseif(count == 2)
                                P2 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Right Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Left sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Left sample tile for focusing.'); % UR --> LL
                            elseif(count == 3)
                                P3 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Lower Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Right sample tile for focusing.'); % LL --> LR
                            end
                            delete(tile_highlight1);
                            patch(closed_rect(:,1),closed_rect(:,2),'green');
                
                        elseif(strcmp(use_tile_ans,'No - choose a new tile') == 1)
                            disp('Choose a new tile to use for focusing.');
                        end
                    end
                end
                
                % Calculate Z-focus values across the entire set of tiles
                % use 3 selected points on the plane to find plane equation
                N = cross(P1-P2,P1-P3); % find normal vector
                N = N./norm(N); % normalaze the value
                A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
                x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
                x = app.x_img_mesh; %set values for x,y
                y = app.y_img_mesh;
                z_img_mesh = (-A.*(x-x0)-B.*(y-y0)+C.*z0)./C; % find z values with plane equation
                
                app.z_img_mesh = z_img_mesh;
                cd(app.rectangular_stitch_folder);
                
                save('image_z_coords.mat','z_img_mesh','-v7.3');
                disp('Saved Z-focus values to image_z_coords.mat');
                close(fig1);
            end

        end

        % Button pushed function: set_up_left_button
        function set_up_left_buttonButtonPushed(app, event)
             % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);

            if(all([app.ULx, app.ULy, app.grid_size]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;
            
                % Choose magnification
                %[mag, app.mag_index] = choose_magnification(app);
                mag = app.mag;
                % Compute actual xy locations for rectangular grid acquisition
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                x_max = app.ULx;
                x_step =app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_x = app.grid_size(1,2);
                
                y_min = app.ULy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_y = app.grid_size(1,1);
                
                x_min = x_max-x_step*(grid_size_x-1); % minimum coordinate for x axis
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min - x_step/2):x_step:(x_max + x_step/2); % Step is divided by 2 to center frame over boundary
            
                y_max = y_min+y_step*(grid_size_y-1); % maximum coordinate for y axis
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);
                
                %grid plotting
                plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);
                
                %save coordinates for opposite corner
                app.LRx = x_min;
                app.LRy = y_max;          
                %app.LRx_coord.Text = num2str(app.LRx);
                %app.LRy_coord.Text = num2str(app.LRy);
                
                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                %app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
            % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
        end

        % Value changed function: Grid_yentry
        function Grid_yentryValueChanged(app, event)
                app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
                reset_grid_parameters(app)
                update_grid(app)
                app.Grid_size_status.Text = sprintf('%dX%d', app.Grid_xentry.Value, app.Grid_yentry.Value);
        end

        % Value changed function: Grid_xentry
        function Grid_xentryValueChanged(app, event)
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            reset_grid_parameters(app);
            update_grid(app);
            app.Grid_size_status.Text = sprintf('%dX%d', app.Grid_xentry.Value, app.Grid_yentry.Value);
        end

        % Value changed function: Exposure_time, Exposure_time_3
        function Exposure_timeValueChanged(app, event)
            value = app.Exposure_time.Value;
            app.Exposure_time_2.Value = value;
            app.Exposure_time_3.Value = value;
            
            % acquire Preview image whenever the Exposure time changes
            acquire_preview_image(app)
            
        end

        % Value changed function: Exposure_time_2
        function Exposure_time_2ValueChanged(app, event)
            value = app.Exposure_time_2.Value;
            app.Exposure_time.Value = value;
            app.Exposure_time_3.Value = value;
            
            % acquire Preview image whenever the Exposure time changes
            acquire_preview_image(app)
            
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            selpath = uigetdir('C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\Images','Get Save Directory');
            C = strsplit(selpath,'\');
            selpath = C{:,end};
            
            if ~isequal(selpath,0) % if a directory is selected
                app.SaveDirectory.Value = selpath;
                
            end
        end

        % Value changed function: AcquisitionType
        function AcquisitionTypeValueChanged(app, event)
            app.Acquisition_method.Text = app.AcquisitionType.Value;
            app.StitchingType.Enable = 'on';
            if strcmp('Widefield (4X)', app.AcquisitionType.Value)
                app.StitchingType.Enable = 'off';
                value = '4X';
                app.MagnificationDropDown.Value = value;
                app.Magnification_status.Text = value;
                app.mag_index = find(app.mags == str2num(erase(convertCharsToStrings(value),"X"))); %Find index from the Mag character
                app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            end 
            
            if strcmp(app.Acquisition_method.Text, 'Stitching')
                if app.Grid_select_points <4
                    app.GridStatusLamp.Color = 'red';
                end 
            end
        end

        % Button pushed function: SetUpperLeftButton
        function SetUpperLeftButtonPushed(app, event)
            % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);
            reset_grid_parameters(app);
            app.SelectPositionButton.Enable = 'on';
            Check_UL_position(app); % check to make sure the UL and LR positions are good
            
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                %calc grid size then just do normal update
                x_min = app.LRx;
                x_max = app.ULx;
                x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                x_max = x_max + x_step/2; % increase to include the point itself
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_xentry.Value = length(x_plot_grid);
                
                y_min = app.ULy;
                y_max = app.LRy; 
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                y_max = y_max + y_step/2;  % increase to include the point itself
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_yentry.Value = length(y_plot_grid);
            end

            update_grid(app);
        end

        % Button pushed function: SetLowerRightButton
        function SetLowerRightButtonPushed(app, event)
            [app.LRx, app.LRy] = read_motor_position(app);
            Check_LR_position(app); % check UL and LR to make sure they are valid
            
            app.SelectPositionButton.Enable = 'on';
            app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
            reset_grid_parameters(app);
            %calc grid size then just do normal update
            x_min = app.LRx;
            x_max = app.ULx;
            x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
            x_max = x_max + x_step/2; % increase to include the point itself
            x_img = x_min:x_step:x_max;
            x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_xentry.Value = length(x_plot_grid);
            
            y_min = app.ULy;
            y_max = app.LRy; 
            y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
            y_max = y_max + y_step/2;  % increase to include the point itself
            y_img = y_min:y_step:y_max;
            y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_yentry.Value = length(y_plot_grid);
 
            update_grid(app);   
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if (app.grid_select_index(1) >1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end 
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if (app.grid_select_index(2) < app.Grid_xentry.Value)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app);
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if (app.grid_select_index(2) > 1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if (app.grid_select_index(1) < app.Grid_yentry.Value)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                % move motor based on index of the x and y image mesh grid
                move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                 app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
             
                acquire_preview_image(app)
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            app.x_img_mesh
            app.y_img_mesh
            switch key
                case 'uparrow'
                    if (app.grid_select_index(2) < app.Grid_yentry.Value)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'downarrow'
                    if (app.grid_select_index(2) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'rightarrow'
                    if (app.grid_select_index(1) < app.Grid_xentry.Value) 
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
                case 'leftarrow'
                    if (app.grid_select_index(1) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                        
                        acquire_preview_image(app)
                    end
            end
        end

        % Button pushed function: SelectPositionButton
        function SelectPositionButtonPushed(app, event)
            
            if (app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor == [0 1 0])
                message = 'Location already Selected for Stitching';
                uiwait(warndlg(message));
            else
                % if auto focusing is selected then find the correct values
                
                if app.AutoFocus.Value
                    app.System_status.Text = 'Auto-Focusing';
                    app.autofocus_start = 0;
                    %[img, current_z] = autofocusing(app, 0, app.Exposure_time.Value, '10X', 'nothing');
                    [~, current_z] = autofocusing(app, 0, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
                    app.autofocus_start = 1;
                    disp(current_z)
                end
                
                % get all positions and save to a global Plane var to calculate
                % plane later on
                z_focus = app.mmc.getPosition;
                [x_pos, y_pos] = read_motor_position(app);
                
                
                app.grid_plane(:,:,app.Grid_select_points) = [x_pos, y_pos, z_focus];
                app.Grid_select_points = app.Grid_select_points + 1;
                app.UnSelectPositionButton.Enable = 'on';
                
                acquire_preview_image(app);
                
                app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor = 'Green';
                
                % move to a new location
                if (app.grid_select_index(1) < app.Grid_yentry.Value) && (app.Grid_select_points == 2)
                    app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                    app.grid_select_index(1) = app.grid_select_index(1) +1;
                    app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                elseif (app.grid_select_index(2) < app.Grid_xentry.Value) && (app.Grid_select_points == 3)
                    app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                    app.grid_select_index(2) = app.grid_select_index(2) +1;
                    app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'red';
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                end 
                    
                acquire_preview_image(app);
                
                if app.Grid_select_points >3
                    app.SelectPositionButton.Enable = 'off';
                    z_plane = calc_z_plane(app);
                    app.z_img_mesh = z_plane;
                    app.GridStatusLamp.Color = 'Green';
                    
                end
                
            end     
        end

        % Button pushed function: UnSelectPositionButton
        function UnSelectPositionButtonPushed(app, event)
            if (app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor == [0 1 0])
                app.rect_handler(app.grid_select_index(1),app.grid_select_index(2)).FaceColor = 'White';
                
                if app.Grid_select_points >1
                    app.grid_plane(:,:,app.Grid_select_points) = [0 0 0];
                    app.Grid_select_points = app.Grid_select_points - 1;
                end
                
                if app.Grid_select_points < 4
                    app.SelectPositionButton.Enable = 'on';
                end
            else
                message = 'Location is not currently Selected for Stitching';
                uiwait(warndlg(message));
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.mmc.reset()
                disp('Camera Disconnected')
            catch 
                disp('Could not reset camera')
            end
            try
                disconnect_all_motors(app)
                disp('Motors Disconnected')
            catch 
                disp('Couldnt Disconnect Motor')
            end
            
            delete(app)
            
        end

        % Callback function
        function OpenMicroManagerButtonPushed(app, event)
            try
                app.System_status.Text = 'Moving to Micro-Manager';
                app.mmc.reset();
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';
                app.SnapShotPreviewButton.Enable = 'off';
                app.AcquireImagesButton.Enable = 'off';
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
            catch 
                disp('MicroManager is already Loaded')
            end
        end

        % Callback function
        function OpenFIJIButtonPushed(app, event)
            javaaddpath("C:\Program Files\MATLAB\R2020a\java\jar\mij.jar");
            MIJ.start('C:\Program Files\Fiji.app\plugins'); % actually start fiji with the correct plugins 
            
        end

        % Value changed function: Step_size
        function Step_sizeValueChanged(app, event)
            value = app.Step_size.Value;
            app.Step_size.Value = value;
            app.Step_size_2.Value = value;
        end

        % Value changed function: CameraStatus
        function CameraStatusValueChanged(app, event)
            value = app.CameraStatus.Value;
            app.mmc.reset();
            if (strcmp(value, 'MicroManager'))
                app.System_status.Text = 'Moving to Micro-Manager';
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';
                app.SnapShotPreviewButton.Enable = 'off';
                app.AcquireImagesButton.Enable = 'off';
                app.Exposure_time_2.Enable = 'off';
                app.x_coord_entry.Enable = 'off';
                app.y_coord_entry.Enable = 'off';
                app.FileName.Enable = 'off';
                app.Step_size.Enable = 'off';
                try
                    app.mmc.reset();
                    !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
                catch 
                    !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe  
                end

                app.System_status.Text = 'Standby';
            elseif (strcmp(value, 'MATLAB')) 
                
                app.System_status.Text = 'Loading Camera to MATLAB';
                
                %system('TASKKILL -f -im javaw.exe')
                try
                    load_camera_with_mmc(app);
                    
                    app.mmc.setConfig('lightpath','Side Port'); % change light path to look at eyepiece (dark field)
                    formatSpec = '%.1f';
                    app.z_position = app.mmc.getPosition;
                    app.mmc.setConfig('Objective', app.MagnificationDropDown.Value);
                    app.FocusPos.Text = num2str(app.z_position, formatSpec);
                    app.System_status.Text = 'Standby';
                    app.FocusDownButton.Enable = 'on';
                    app.FocusUpButton.Enable = 'on';
                    app.SnapShotPreviewButton.Enable = 'on';
                    app.AcquireImagesButton.Enable = 'on';  
                    app.Exposure_time_2.Enable = 'on';
                    app.x_coord_entry.Enable = 'on';
                    app.y_coord_entry.Enable = 'on';
                    app.FileName.Enable = 'on';
                    app.Step_size.Enable = 'on';
                    acquire_preview_image(app)
                catch ME
                    message = 'Error Loading Camera, Restart Camera and Try again';
                    uiwait(warndlg(message));
                end
                    
            else 
                app.System_status.Text = 'Switching to Eyepiece';
                try
                    app.mmc.reset();
                catch ME
                    disp('Camera Not Connected Just yet')
                end
                
                try
                    %system('TASKKILL -f -im javaw.exe')
                    load_camera_with_mmc(app);
                    
                    % Set camera to Eye peice on microscope
                    app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
                    formatSpec = '%.1f';
                    app.z_position = app.mmc.getPosition;
                    app.FocusPos.Text = num2str(app.z_position, formatSpec);
                    app.System_status.Text = 'Standby';
                    app.FocusDownButton.Enable = 'on';
                    app.FocusUpButton.Enable = 'on';
                    app.SnapShotPreviewButton.Enable = 'on';
                    app.AcquireImagesButton.Enable = 'on'; 
                    acquire_preview_image(app)
                catch ME
                    message = 'Error Loading EyePiece, Restart Camera and Try again';
                    uiwait(warndlg(message));
                end
                
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            %% allow up to 3 ROIs
            app.ROI_counter = app.ROI_counter + 1;
            if app.ROI_counter == 1
                ROI_string = sprintf('ROI-%d %s', app.ROI_counter, app.ROI_Magnification.Value);
                %app.ROI_param{app.ROI_counter} = {app.ROI_Magnification.Value, app.ROI_mesh_x, app.ROI_mesh_y}
                app.ROI_handler(app.ROI_counter,1) = drawrectangle('Parent', app.Image_Axis, 'Position', [0 0 500 500], 'Label',ROI_string);
                addlistener(app.ROI_handler(app.ROI_counter,1),'ROIMoved',@app.all_events);
            elseif app.ROI_counter ==2
                ROI_string = sprintf('ROI-%d %s', app.ROI_counter, app.ROI_Magnification.Value);
                %app.ROI_param{app.ROI_counter} = {app.ROI_Magnification.Value, app.ROI_mesh_x, app.ROI_mesh_y}
                app.ROI_handler(app.ROI_counter,1) = drawrectangle('Parent', app.Image_Axis, 'Position', [0 0 500 500], 'Label',ROI_string);
                addlistener(app.ROI_handler(app.ROI_counter,1),'ROIMoved',@app.all_events2);
            elseif app.ROI_counter ==3
                ROI_string = sprintf('ROI-%d %s', app.ROI_counter, app.ROI_Magnification.Value);
                %app.ROI_param{app.ROI_counter} = {app.ROI_Magnification.Value, app.ROI_mesh_x, app.ROI_mesh_y}
                app.ROI_handler(app.ROI_counter,1) = drawrectangle('Parent', app.Image_Axis, 'Position', [0 0 500 500], 'Label',ROI_string);
                addlistener(app.ROI_handler(app.ROI_counter,1),'ROIMoved',@app.all_events3);
            else
                message = 'Max # of ROIs Reached';
                uiwait(warndlg(message));
            end
        end

        % Value changed function: ROI_Magnification
        function ROI_MagnificationValueChanged(app, event)
            value = app.ROI_Magnification.Value;
            app.ROI_mag_index = find(app.mags == str2num(erase(convertCharsToStrings(value),"X"))); %Find index from the Mag character
        end

        % Value changed function: GridSelectionDropDown
        function GridSelectionDropDownValueChanged(app, event)
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                app.SetLowerRightButton.Enable = 'on';
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
                
                %calc grid size then just do normal update from top left 
                x_min = app.LRx;
                x_max = app.ULx;
                x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                x_max = x_max + x_step/2; % increase to include the point itself
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_xentry.Value = length(x_plot_grid);
                
                y_min = app.ULy;
                y_max = app.LRy; 
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                y_max = y_max + y_step/2;  % increase to include the point itself
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_yentry.Value = length(y_plot_grid);
                
                app.Grid_xentry.Enable = 'off';
                app.Grid_yentry.Enable = 'off';
                
                update_grid(app)
            else
                app.SetLowerRightButton.Enable = 'off';
                app.LR_Location.Text = 'NA';
                app.Grid_xentry.Enable = 'on';
                app.Grid_yentry.Enable = 'on';
                app.Grid_xentry.Value = 2;
                app.Grid_yentry.Value = 2;
                update_grid(app)
            end
            
        end

        % Value changed function: MicroscopeSetup
        function MicroscopeSetupValueChanged(app, event)
            value = app.MicroscopeSetup.Value;
            app.Microscopy_status.Text = value;
            if strcmp(value, 'qBRM')
                msg = 'Switching to qBRM imaging requires removing the Illumination Waveplate';
                uiwait(msgbox(msg));
            end
        end

        % Button pushed function: AutoFocusButton
        function AutoFocusButtonPushed(app, event)
            app.autofocus_start = 0;
            app.System_status.Text = sprintf('Setting focus with %s', app.MagnificationDropDown.Value);
            [img, app.z_position] = autofocusing(app, 0, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
            app.autofocus_start = 1;
            
            imshow(uint8(img), 'Parent', app.Image_Axis, 'InitialMagnification', 'fit');
            app.Image_Preview_2.ImageSource = repmat(uint8(img), 1, 1, 3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.AcquisitionStatusPanel = uipanel(app.AcquisitionTab);
            app.AcquisitionStatusPanel.Title = 'Acquisition Status';
            app.GridStatusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel.Text = 'Grid Status';
            app.GridStatusLamp = uilamp(app.AcquisitionStatusPanel);
            app.AcquireImagesButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireImagesButtonPushed, true);
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.filename_ext = uilabel(app.AcquisitionTab);
            app.filename_ext.Text = '_exposure-time_magnification';
            app.SystemStatusPanel = uipanel(app.AcquisitionTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.Progress_Bar = uiaxes(app.SystemStatusPanel);
            title(app.Progress_Bar, '0%')
            app.Progress_Bar.XLim = [0 1];
            app.Progress_Bar.YLim = [0 1];
            app.Progress_Bar.XTick = [];
            app.Progress_Bar.XTickLabel = '';
            app.Progress_Bar.YTick = [];
            app.Progress_Bar.YTickLabel = '';
            app.MagnificationLabel_2 = uilabel(app.SystemStatusPanel);
            app.MagnificationLabel_2.Text = 'Magnification:';
            app.MicroscopyMethodLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopyMethodLabel.Text = 'Microscopy Method: ';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Magnification_status = uilabel(app.SystemStatusPanel);
            app.Magnification_status.Text = '20x';
            app.Microscopy_status = uilabel(app.SystemStatusPanel);
            app.Microscopy_status.Text = 'CCP-BRM';
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status.Text = '2x2';
            app.Label_5 = uilabel(app.SystemStatusPanel);
            app.Label_5.Text = '--------------------------------------------------------------------------------------';
            app.System_status = uilabel(app.SystemStatusPanel);
            app.System_status.Text = 'Standby';
            app.CurrentOperationLabel = uilabel(app.SystemStatusPanel);
            app.CurrentOperationLabel.Text = 'Current Operation:';
            app.AcquisitionTypeLabel_2 = uilabel(app.SystemStatusPanel);
            app.AcquisitionTypeLabel_2.Text = 'Acquisition Type:';
            app.Acquisition_method = uilabel(app.SystemStatusPanel);
            app.Acquisition_method.Text = 'Single Image';
            app.CameraStatusDropDownLabel = uilabel(app.SystemStatusPanel);
            app.CameraStatusDropDownLabel.Text = 'Camera Status:';
            app.CameraStatus = uidropdown(app.SystemStatusPanel);
            app.CameraStatus.Items = {'MATLAB', 'MicroManager', 'EyePiece'};
            app.CameraStatus.ValueChangedFcn = createCallbackFcn(app, @CameraStatusValueChanged, true);
            app.CameraStatus.Value = 'MATLAB';
            app.StageControlPanel = uipanel(app.AcquisitionTab);
            app.StageControlPanel.Title = 'Stage Control';
            app.x_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.StageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.StageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.MoveXYmotorButton = uibutton(app.StageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_3 = uilabel(app.StageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.StageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.StageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.StageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.FocusUpButton = uibutton(app.StageControlPanel, 'push');
            app.FocusUpButton.ButtonPushedFcn = createCallbackFcn(app, @FocusUpButtonPushed, true);
            app.FocusUpButton.Text = 'Focus Up';
            app.FocusPosLabel = uilabel(app.StageControlPanel);
            app.FocusPosLabel.Text = 'Focus-Pos';
            app.FocusPos = uilabel(app.StageControlPanel);
            app.FocusPos.Text = 'NaN';
            app.FocusDownButton = uibutton(app.StageControlPanel, 'push');
            app.FocusDownButton.ButtonPushedFcn = createCallbackFcn(app, @FocusDownButtonPushed, true);
            app.FocusDownButton.Text = 'Focus Down';
            app.FocusPos_2 = uilabel(app.StageControlPanel);
            app.FocusPos_2.Text = 'NA';
            app.StepSizeEditFieldLabel = uilabel(app.StageControlPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.Step_size = uieditfield(app.StageControlPanel, 'numeric');
            app.Step_size.ValueChangedFcn = createCallbackFcn(app, @Step_sizeValueChanged, true);
            app.Step_size.Value = 1;
            app.mLabel = uilabel(app.StageControlPanel);
            app.mLabel.Text = 'µm';
            app.AutoFocusButton = uibutton(app.StageControlPanel, 'push');
            app.AutoFocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFocusButtonPushed, true);
            app.AutoFocusButton.Text = 'AutoFocus';
            app.SnapShotPreviewButton = uibutton(app.AcquisitionTab, 'push');
            app.SnapShotPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotPreviewButtonPushed, true);
            app.SnapShotPreviewButton.Text = 'SnapShot Preview';
            app.ImagePreviewPanel = uipanel(app.AcquisitionTab);
            app.ImagePreviewPanel.Title = 'Image Preview';
            app.Image_Axis = uiaxes(app.ImagePreviewPanel);
            app.Image_Axis.XTick = [];
            app.Image_Axis.YTick = [];
            app.AddROIButton = uibutton(app.ImagePreviewPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Text = 'Add ROI';
            app.MagnificationDropDown_2Label = uilabel(app.ImagePreviewPanel);
            app.MagnificationDropDown_2Label.Enable = 'off';
            app.MagnificationDropDown_2Label.Text = 'Magnification';
            app.ROI_Magnification = uidropdown(app.ImagePreviewPanel);
            app.ROI_Magnification.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.ROI_Magnification.ValueChangedFcn = createCallbackFcn(app, @ROI_MagnificationValueChanged, true);
            app.ROI_Magnification.Enable = 'off';
            app.ROI_Magnification.Value = '4X';
            app.FilenameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FileName = uieditfield(app.AcquisitionTab, 'text');
            app.ExpTimemsLabel = uilabel(app.AcquisitionTab);
            app.ExpTimemsLabel.Text = 'Exp Time (ms)';
            app.Exposure_time_2 = uieditfield(app.AcquisitionTab, 'numeric');
            app.Exposure_time_2.Limits = [0 5000];
            app.Exposure_time_2.ValueChangedFcn = createCallbackFcn(app, @Exposure_time_2ValueChanged, true);
            app.Exposure_time_2.Value = 400;
            app.Camera_view = uibutton(app.AcquisitionTab, 'push');
            app.Camera_view.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view.Text = 'Camera View';
            app.CurrentDirectory = uilabel(app.AcquisitionTab);
            app.CurrentDirectory.Text = 'Current Directory:  ';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ImageSettingsPanel = uipanel(app.SettingsTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.MagnificationLabel = uilabel(app.ImageSettingsPanel);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.ImageSettingsPanel);
            app.MagnificationDropDown.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = '20X';
            app.ExposureTime1Label = uilabel(app.ImageSettingsPanel);
            app.ExposureTime1Label.Text = 'Exposure Time (1):';
            app.ExposureTime1 = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime1.Limits = [0 1500];
            app.ExposureTime1.Value = 50;
            app.msLabel_2 = uilabel(app.ImageSettingsPanel);
            app.msLabel_2.Text = 'ms';
            app.msLabel = uilabel(app.ImageSettingsPanel);
            app.msLabel.Text = 'ms';
            app.ExposureTime2EditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2):';
            app.ExposureTime2 = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime2.Limits = [0 1500];
            app.ExposureTime2.Value = 100;
            app.AcquisitionPanel = uipanel(app.SettingsTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.MicroscopeSetupLabel = uilabel(app.AcquisitionPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.MicroscopeSetup = uidropdown(app.AcquisitionPanel);
            app.MicroscopeSetup.Items = {'CCP-BRM', 'qBRM', 'Brightfield Microscopy', 'Rotating Linear Polarizer'};
            app.MicroscopeSetup.ValueChangedFcn = createCallbackFcn(app, @MicroscopeSetupValueChanged, true);
            app.MicroscopeSetup.Value = 'CCP-BRM';
            app.AcquisitionTypeLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitionTypeLabel.Text = 'Acquisition Type:';
            app.AcquisitionType = uidropdown(app.AcquisitionPanel);
            app.AcquisitionType.Items = {'Single Image', 'Single Z-Stack', 'Stitching', 'Widefield (4X)'};
            app.AcquisitionType.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTypeValueChanged, true);
            app.AcquisitionType.Value = 'Single Image';
            app.ExposureTimeLabel = uilabel(app.AcquisitionPanel);
            app.ExposureTimeLabel.Text = 'Exposure Time:';
            app.Exposure_time = uieditfield(app.AcquisitionPanel, 'numeric');
            app.Exposure_time.Limits = [0 5000];
            app.Exposure_time.ValueChangedFcn = createCallbackFcn(app, @Exposure_timeValueChanged, true);
            app.Exposure_time.Value = 400;
            app.AutoFocus = uicheckbox(app.AcquisitionPanel);
            app.AutoFocus.Text = 'Auto Focus';
            app.LinearPolarizersPanel = uipanel(app.SettingsTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.Det_polarizer = uilabel(app.LinearPolarizersPanel);
            app.Det_polarizer.Text = '14.8 °';
            app.ill_polarizer = uilabel(app.LinearPolarizersPanel);
            app.ill_polarizer.Text = '120.03 ° ';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Rotate_Detection = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection.Text = 'Rotate';
            app.Label_2 = uilabel(app.LinearPolarizersPanel);
            app.Label_2.Text = '°';
            app.Label = uilabel(app.LinearPolarizersPanel);
            app.Label.Text = '°';
            app.DetectionEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionEditFieldLabel.Text = 'Detection';
            app.DetectionEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.DetectionEditField.Limits = [0 180];
            app.IlluminationEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationEditField.Limits = [0 180];
            app.Rotate_Detection_2 = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection_2.Text = 'Rotate';
            app.BrowseButton_2 = uibutton(app.SettingsTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.SaveDirectoryDocuments_BU_Bigio_ImagesLabel = uilabel(app.SettingsTab);
            app.SaveDirectoryDocuments_BU_Bigio_ImagesLabel.Text = 'Save Directory:    Documents/_BU /_Bigio/_Images/';
            app.SaveDirectory = uieditfield(app.SettingsTab, 'text');
            app.GridLayoutPanel = uipanel(app.SettingsTab);
            app.GridLayoutPanel.Title = 'Grid Layout';
            app.UIAxes = uiaxes(app.GridLayoutPanel);
            title(app.UIAxes, 'Grid Selection')
            xlabel(app.UIAxes, 'X Position')
            ylabel(app.UIAxes, 'Y Position')
            app.TabGroup2 = uitabgroup(app.GridLayoutPanel);
            app.CornersTab = uitab(app.TabGroup2);
            app.CornersTab.Title = '2 Corners';
            app.calculate_grid_button = uibutton(app.CornersTab, 'push');
            app.calculate_grid_button.Tag = 'calculate_grid_button';
            app.calculate_grid_button.Text = 'Calculate Grid Size';
            app.set_UL_coords_button = uibutton(app.CornersTab, 'push');
            app.set_UL_coords_button.Tag = 'set_UL_coords_button';
            app.set_UL_coords_button.Text = 'Set UL';
            app.set_LR_coords_button = uibutton(app.CornersTab, 'push');
            app.set_LR_coords_button.Tag = 'set_LR_coords_button';
            app.set_LR_coords_button.Text = 'Set LR';
            app.SingleCornerTab = uitab(app.TabGroup2);
            app.SingleCornerTab.Title = 'Single Corner';
            app.set_grid_button = uibutton(app.SingleCornerTab, 'push');
            app.set_grid_button.Tag = 'set_grid_button';
            app.set_grid_button.Text = 'Set Grid Size';
            app.Image_3 = uiimage(app.SingleCornerTab);
            app.Image_3.ImageSource = 'box.png';
            app.set_up_left_button = uibutton(app.SingleCornerTab, 'push');
            app.set_up_left_button.ButtonPushedFcn = createCallbackFcn(app, @set_up_left_buttonButtonPushed, true);
            app.set_up_left_button.Tag = 'set_up_left_button';
            app.set_up_left_button.Text = 'Set UL';
            app.set_low_right_button = uibutton(app.SingleCornerTab, 'push');
            app.set_low_right_button.Tag = 'set_low_right_button';
            app.set_low_right_button.Text = 'Set LR';
            app.LoadGridButton = uibutton(app.GridLayoutPanel, 'push');
            app.LoadGridButton.Text = 'Load Grid ';
            app.GridSizeLabel = uilabel(app.GridLayoutPanel);
            app.GridSizeLabel.Text = 'Grid Size: ';
            app.Grid_xentry = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Grid_xentry.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.Grid_xentry.Value = 2;
            app.xEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Grid_yentry = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Grid_yentry.ValueChangedFcn = createCallbackFcn(app, @Grid_yentryValueChanged, true);
            app.Grid_yentry.Value = 2;
            app.StitchingTypeDropDownLabel = uilabel(app.GridLayoutPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingType = uidropdown(app.GridLayoutPanel);
            app.StitchingType.Items = {'Single Plane', 'Multi-Plane'};
            app.StitchingType.Value = 'Single Plane';
            app.Image_Preview_2 = uiimage(app.GridLayoutPanel);
            app.FocusUpButton_2 = uibutton(app.GridLayoutPanel, 'push');
            app.FocusUpButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusUpButtonPushed, true);
            app.FocusUpButton_2.Text = 'Focus Up';
            app.FocusPosLabel_2 = uilabel(app.GridLayoutPanel);
            app.FocusPosLabel_2.Text = 'Focus-Pos';
            app.FocusPos_3 = uilabel(app.GridLayoutPanel);
            app.FocusPos_3.Text = 'NaN';
            app.FocusDownButton_2 = uibutton(app.GridLayoutPanel, 'push');
            app.FocusDownButton_2.ButtonPushedFcn = createCallbackFcn(app, @FocusDownButtonPushed, true);
            app.FocusDownButton_2.Text = 'Focus Down';
            app.mLabel_2 = uilabel(app.GridLayoutPanel);
            app.mLabel_2.Text = 'µm';
            app.StepSizeEditFieldLabel_2 = uilabel(app.GridLayoutPanel);
            app.StepSizeEditFieldLabel_2.Text = 'Step Size';
            app.Step_size_2 = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Step_size_2.Value = 1;
            app.ExpTimemsLabel_2 = uilabel(app.GridLayoutPanel);
            app.ExpTimemsLabel_2.Text = 'Exp Time (ms)';
            app.Exposure_time_3 = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Exposure_time_3.Limits = [0 5000];
            app.Exposure_time_3.ValueChangedFcn = createCallbackFcn(app, @Exposure_timeValueChanged, true);
            app.Exposure_time_3.Value = 400;
            app.DownButton = uibutton(app.GridLayoutPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.GridLayoutPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.UpButton = uibutton(app.GridLayoutPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.GridLayoutPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.SetZFocusforPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetZFocusforPositionButton.Enable = 'off';
            app.SetZFocusforPositionButton.Text = 'Set Z-Focus for Position ';
            app.Label_6 = uilabel(app.GridLayoutPanel);
            app.Label_6.Text = '_________________________________________________________________________________________________________________________';
            app.UnSelectPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.UnSelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectPositionButtonPushed, true);
            app.UnSelectPositionButton.Text = 'Un-Select Position';
            app.SelectPositionButton = uibutton(app.GridLayoutPanel, 'push');
            app.SelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPositionButtonPushed, true);
            app.SelectPositionButton.Text = 'Select Position';
            app.Camera_view_2 = uibutton(app.GridLayoutPanel, 'push');
            app.Camera_view_2.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view_2.Text = 'Change Camera View';
            app.Camera_status_label_2 = uilabel(app.GridLayoutPanel);
            app.Camera_status_label_2.Text = 'Camera Status: ';
            app.Current_camera_2 = uilabel(app.GridLayoutPanel);
            app.Current_camera_2.Text = 'Micromanager';
            app.GridSelectionDropDownLabel = uilabel(app.GridLayoutPanel);
            app.GridSelectionDropDownLabel.Text = 'Grid Selection';
            app.GridSelectionDropDown = uidropdown(app.GridLayoutPanel);
            app.GridSelectionDropDown.Items = {'Single Corner', 'Two Corners'};
            app.GridSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSelectionDropDownValueChanged, true);
            app.GridSelectionDropDown.Value = 'Single Corner';
            app.SetUpperLeftButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetUpperLeftButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpperLeftButtonPushed, true);
            app.SetUpperLeftButton.Text = 'Set Upper Left';
            app.SetLowerRightButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetLowerRightButton.ButtonPushedFcn = createCallbackFcn(app, @SetLowerRightButtonPushed, true);
            app.SetLowerRightButton.Enable = 'off';
            app.SetLowerRightButton.Text = 'Set Lower Right';
            app.ULLabel = uilabel(app.GridLayoutPanel);
            app.ULLabel.Text = 'UL:';
            app.UL_Location_lbl = uilabel(app.GridLayoutPanel);
            app.UL_Location_lbl.Text = '(55,37.5)';
            app.LRLabel = uilabel(app.GridLayoutPanel);
            app.LRLabel.Text = 'LR:';
            app.LR_Location = uilabel(app.GridLayoutPanel);
            app.LR_Location.Text = 'NA';
            app.Image_2 = uiimage(app.GridLayoutPanel);
            app.Image_2.ImageSource = 'box.png';
            app.ULLabel_2 = uilabel(app.GridLayoutPanel);
            app.ULLabel_2.Text = 'UL';
            app.LRLabel_2 = uilabel(app.GridLayoutPanel);
            app.LRLabel_2.Text = 'LR';
            app.ROISelectionListBoxLabel = uilabel(app.GridLayoutPanel);
            app.ROISelectionListBoxLabel.Enable = 'off';
            app.ROISelectionListBoxLabel.Visible = 'off';
            app.ROISelectionListBoxLabel.Text = 'ROI Selection';
            app.ROISelection = uilistbox(app.GridLayoutPanel);
            app.ROISelection.Items = {};
            app.ROISelection.Enable = 'off';
            app.ROISelection.Visible = 'off';
            app.ROISelection.Value = {};
            app.SampleParametersPanel = uipanel(app.SettingsTab);
            app.SampleParametersPanel.Title = 'Sample Parameters';
            app.SampleThicknessEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleThicknessEditFieldLabel.Text = 'Sample Thickness';
            app.SampleThickness = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleThickness.Limits = [-10 Inf];
            app.SampleThickness.Value = 30;
            app.mLabel_3 = uilabel(app.SampleParametersPanel);
            app.mLabel_3.Text = 'µm';
            app.SampleTypeEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleTypeEditFieldLabel.Text = 'Sample Type';
            app.SampleTypeEditField = uieditfield(app.SampleParametersPanel, 'text');
            app.SampleTypeEditField.Value = 'Human';
            app.mLabel_4 = uilabel(app.SampleParametersPanel);
            app.mLabel_4.Text = 'µm';
            app.SamplethicknessOffsetLabel = uilabel(app.SampleParametersPanel);
            app.SamplethicknessOffsetLabel.Text = '*Sample thickness ± Offset  ';
            app.mLabel_5 = uilabel(app.SampleParametersPanel);
            app.mLabel_5.Text = 'µm';
            app.ZStackOffsetLabel = uilabel(app.SampleParametersPanel);
            app.ZStackOffsetLabel.Text = 'Z-Stack Offset';
            app.ZStackoffset = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSizeLabel = uilabel(app.SampleParametersPanel);
            app.ZStackStepSizeLabel.Enable = 'off';
            app.ZStackStepSizeLabel.Text = 'Z-Stack Step Size';
            app.ZStackStepSize = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSize.Limits = [0 Inf];
            app.ZStackStepSize.Editable = 'off';
            app.ZStackStepSize.Enable = 'off';
            app.ZStackStepSize.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRM_Microscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end