classdef camera_preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CameraBinningDropDown         matlab.ui.control.DropDown
        CameraBinningDropDownLabel    matlab.ui.control.Label
        FPSLabel                      matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        ResolutionLabel               matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        X0Y0Label                     matlab.ui.control.Label
        ROIPositionpxLabel            matlab.ui.control.Label
        AutoContrastButton            matlab.ui.control.StateButton
        ExposureTimemsEditField       matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel  matlab.ui.control.Label
        SnapShotButton                matlab.ui.control.Button
        Histogram_axis                matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vid; % Description
        hImage;
        cam_info;
        video_setting;
        src;
        original_position;
        image_size;
        patch_handle;
        top_left;
        ROI_width_height;
        histo;
        camera_pixels = [3200, 2200];
    end
    
    methods (Access = private)
        
        function Load_camera_default(app)
            try
                %%%% can use manual trigger mode to greatly increase frame
                %%%% rate for the capture of single images
                app.image_size = app.vid.ROIPosition/2;
                app.camera_pixels = [3200, 2200];
                if strcmp(app.CameraBinningDropDown.Value, '2x2')
                    app.camera_pixels = [1600 1100];
                    app.image_size = app.vid.ROIPosition/2;
                end
                app.hImage = image(app.UIAxes, zeros(app.camera_pixels(1),app.camera_pixels(2),1));
                app.UIAxes.XLim = [0,app.camera_pixels(1)];
                app.UIAxes.YLim = [0,app.camera_pixels(2)];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                pbaspect(app.UIAxes,[app.camera_pixels(1),app.camera_pixels(2),1])
                app.vid.FrameGrabInterval = 1;
                app.src = getselectedsource(app.vid);
                value = app.AutoContrastButton.Value;
                autocontrast = {'OFF','ON-AUTO'};
                app.src.AutoContrast = autocontrast{value+1};
                app.src.AutoContrast = autocontrast{value+1};
                app.src.Binning = app.CameraBinningDropDown.Value;
                app.src.Exposure = app.ExposureTimemsEditField.Value;
                % Preview the images
                Camera_preview(app)
            catch ME
                uiwait(errordlg('Camera Was not found', 'ERROR'), 5);
                disable_all_buttons(app);
            end
        end
        
        function disable_all_buttons(app)
            app.AutoContrastButton.Enable = 'off';
            app.SnapShotButton.Enable = 'off';
            app.ExposureTimemsEditField.Enable = 'off';
            app.AutoContrastButton.Enable = 'off';
        end
        
        function mypreview_fcn(app,~,event,himage)
            % Set the value of the text label.
            app.FPSLabel.Text = event.FrameRate;
            % Display the current image frame.
            set(himage, 'CData', event.Data);
            app.histo = histogram(event.Data, 128,'Parent', app.Histogram_axis, 'EdgeColor', 'black', 'FaceColor', 'black');
        end
        
        function Camera_preview(app)
            preview(app.vid,app.hImage)
            setappdata(app.hImage,'UpdatePreviewWindowFcn',@app.mypreview_fcn);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imaqreset
            app.cam_info = imaqhwinfo;
            app.vid = videoinput('pmimaq_2019b',1,'PM-Cam 3200x2200');
            app.vid.FramesPerTrigger = 1;
            Load_camera_default(app);
        end

        % Button pushed function: SnapShotButton
        function SnapShotButtonPushed(app, event)
            stoppreview(app.vid)
            triggerconfig(app.vid, 'manual');
            app.src.AutoContrast = 'OFF';
            % Now that the device is configured for manual triggering, call START.
            % This will cause the device to send data back to MATLAB, but will not log
            % frames to memory at this point.
            start(app.vid)
            tic
            % Measure the time to acquire 20 frames.
            snapshot = getsnapshot(app.vid);
            toc
            stop(app.vid)
            value = app.AutoContrastButton.Value;
            autocontrast = {'OFF','ON-AUTO'};
            app.src.AutoContrast = autocontrast{value+1};
            Camera_preview(app)

        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            value = app.ExposureTimemsEditField.Value;
            app.video_setting.Exposure = value;
        end

        % Value changed function: AutoContrastButton
        function AutoContrastButtonValueChanged(app, event)
            value = app.AutoContrastButton.Value;
            autocontrast = {'OFF','ON-AUTO'};
            app.src.AutoContrast = autocontrast{value+1};
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if (app.UIAxes.CurrentPoint(1,1) > 0) && (app.UIAxes.CurrentPoint(1,1) < app.image_size(3))...
                           && (app.UIAxes.CurrentPoint(1,2) > 0) && (app.UIAxes.CurrentPoint(1,2) < app.image_size(4))
                        app.original_position = [app.UIAxes.CurrentPoint(1,1),app.UIAxes.CurrentPoint(1,2)];
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if (app.UIAxes.CurrentPoint(1,1) > 0) && (app.UIAxes.CurrentPoint(1,1) < app.image_size(3))...
                           && (app.UIAxes.CurrentPoint(1,2) > 0) && (app.UIAxes.CurrentPoint(1,2) < app.image_size(4))
                        app.original_position = [app.UIAxes.CurrentPoint(1,1),app.UIAxes.CurrentPoint(1,2)];
                app.original_position = [];
                stoppreview(app.vid)
                app.hImage = image(app.UIAxes, zeros(round(app.ROI_width_height(1)),round(app.ROI_width_height(2)),1));
                app.UIAxes.XLim = [0,round(app.ROI_width_height(1))];
                app.UIAxes.YLim = [0,round(app.ROI_width_height(2))];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                pbaspect(app.UIAxes,[round(app.ROI_width_height(1)),round(app.ROI_width_height(2)),1])
                app.image_size = app.vid.ROIPosition;
                app.vid.ROIPosition = [round(app.top_left(1)) round(app.top_left(2)) round(app.ROI_width_height(1)) round(app.ROI_width_height(2))];
                Camera_preview(app)
                app.X0Y0Label.Text = sprintf('X: %d Y: %d', round(app.top_left(1)), round(app.top_left(2)));
                app.ResolutionLabel.Text = sprintf('Width: %d Height: %d', round(app.ROI_width_height(1)), round(app.ROI_width_height(2)));
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            delete(app.patch_handle)
            if app.original_position
                if (app.UIAxes.CurrentPoint(1,1) > 0) && (app.UIAxes.CurrentPoint(1,1) < app.image_size(3))...
                           && (app.UIAxes.CurrentPoint(1,2) > 0) && (app.UIAxes.CurrentPoint(1,2) < app.image_size(4))
                    height = app.original_position(2) - app.UIAxes.CurrentPoint(1,2);
                    width = app.original_position(1) - app.UIAxes.CurrentPoint(1,1);
                    box_coordinatesx = [app.original_position(1)-width; app.original_position(1);app.original_position(1);app.original_position(1)-width];
                    box_coordinatesy = [app.original_position(2)-height;app.original_position(2)-height;app.original_position(2);app.original_position(2)];
                    app.patch_handle = patch(box_coordinatesx, box_coordinatesy, 'red', 'FaceColor', 'none', 'EdgeColor', 'red', 'Parent', app.UIAxes);
                    app.ROI_width_height = [abs(width) abs(height)];
                    if height < 0 && width < 0
                        app.top_left = app.original_position;
                    elseif height < 0 && width > 0
                        app.top_left = [app.original_position(1)-abs(width), app.original_position(2)];
                    elseif height > 0 && width < 0
                        app.top_left = [app.original_position(1), app.original_position(2)-abs(height)];
                    else
                        app.top_left = [app.original_position(1)-width, app.original_position(2)-abs(height)];
                    end
                end
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

            switch key
                case 'shift'
                    Load_camera_default(app)
            end
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            stoppreview(app.vid)
            app.hImage = image(app.UIAxes, zeros(app.WidthEditField.Value,app.HeightEditField.Value,1));
            app.UIAxes.XLim = [0,round(app.WidthEditField.Value)];
            app.UIAxes.YLim = [0,round(app.HeightEditField.Value)];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[round(app.WidthEditField.Value),round(app.HeightEditField.Value),1])
            app.image_size = app.vid.ROIPosition;
            center = [1600 1100];
            app.vid.ROIPosition = [(center(1) - round(app.WidthEditField.Value/2)) (center(2)-round(app.HeightEditField.Value/2)) round(app.WidthEditField.Value) round(app.HeightEditField.Value)];
            Camera_preview(app)
           
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            stoppreview(app.vid)
            app.hImage = image(app.UIAxes, zeros(app.WidthEditField.Value,app.HeightEditField.Value,1));
            app.UIAxes.XLim = [0,round(app.WidthEditField.Value)];
            app.UIAxes.YLim = [0,round(app.HeightEditField.Value)];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[round(app.WidthEditField.Value),round(app.HeightEditField.Value),1])
            app.image_size = app.vid.ROIPosition;
            center = [1600 1100];
            app.vid.ROIPosition = [(center(1) - round(app.WidthEditField.Value/2)) (center(2)-round(app.HeightEditField.Value/2)) round(app.WidthEditField.Value) round(app.HeightEditField.Value)];
            Camera_preview(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            imaqreset
            delete(app)
        end

        % Value changed function: CameraBinningDropDown
        function CameraBinningDropDownValueChanged(app, event)
           app.src.Binning = app.CameraBinningDropDown.Value;
           
           pbaspect(app.UIAxes,[app.camera_pixels(1),app.camera_pixels(2),1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Histogram_axis = uiaxes(app.UIFigure);
            app.Histogram_axis.XLim = [0 256];
            app.Histogram_axis.XTick = [];
            app.Histogram_axis.YTick = [];
            app.SnapShotButton = uibutton(app.UIFigure, 'push');
            app.SnapShotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotButtonPushed, true);
            app.SnapShotButton.Text = 'SnapShot';
            app.ExposureTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms):';
            app.ExposureTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.ExposureTimemsEditField.Value = 5;
            app.AutoContrastButton = uibutton(app.UIFigure, 'state');
            app.AutoContrastButton.ValueChangedFcn = createCallbackFcn(app, @AutoContrastButtonValueChanged, true);
            app.AutoContrastButton.Text = 'Auto Contrast';
            app.ROIPositionpxLabel = uilabel(app.UIFigure);
            app.ROIPositionpxLabel.Text = 'ROI Position (px):';
            app.X0Y0Label = uilabel(app.UIFigure);
            app.X0Y0Label.Text = 'X: 0 Y: 0 ';
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 3200;
            app.ResolutionLabel = uilabel(app.UIFigure);
            app.ResolutionLabel.Text = 'Resolution: ';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 2200;
            app.FPSLabel = uilabel(app.UIFigure);
            app.FPSLabel.Text = '0 FPS';
            app.CameraBinningDropDownLabel = uilabel(app.UIFigure);
            app.CameraBinningDropDownLabel.Text = 'Camera Binning';
            app.CameraBinningDropDown = uidropdown(app.UIFigure);
            app.CameraBinningDropDown.Items = {'1x1', '2x2'};
            app.CameraBinningDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraBinningDropDownValueChanged, true);
            app.CameraBinningDropDown.Value = '1x1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_preview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end